classdef GIRAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GIRASUI                        matlab.ui.Figure
        GIRASMenu                      matlab.ui.container.TabGroup
        rcf                            matlab.ui.container.Tab
        rcfSettings                    matlab.ui.container.Panel
        rcfPT                          matlab.ui.container.ButtonGroup
        rcfPT1                         matlab.ui.control.RadioButton
        rcfPT2                         matlab.ui.control.RadioButton
        rcfIT                          matlab.ui.container.ButtonGroup
        rcfIT1                         matlab.ui.control.RadioButton
        rcfIT2                         matlab.ui.control.RadioButton
        rcfET                          matlab.ui.container.ButtonGroup
        rcfET1                         matlab.ui.control.RadioButton
        rcfET2                         matlab.ui.control.RadioButton
        rcfSP                          matlab.ui.container.Panel
        rcfSPset                       matlab.ui.container.ButtonGroup
        rcfSP1                         matlab.ui.control.RadioButton
        rcfSP2                         matlab.ui.control.RadioButton
        XmEditFieldLabel               matlab.ui.control.Label
        rcfSPX                         matlab.ui.control.NumericEditField
        YmEditFieldLabel               matlab.ui.control.Label
        rcfSPY                         matlab.ui.control.NumericEditField
        ZmEditFieldLabel               matlab.ui.control.Label
        rcfSPZ                         matlab.ui.control.NumericEditField
        rcfFiles                       matlab.ui.container.Panel
        ObservationfilesTextAreaLabel  matlab.ui.control.Label
        rcfOFL                         matlab.ui.control.TextArea
        rcfOFBrowse                    matlab.ui.control.Button
        rcfEFBrowse                    matlab.ui.control.Button
        rcfOFClear                     matlab.ui.control.Button
        rcfEFClear                     matlab.ui.control.Button
        rcfNOFmsg                      matlab.ui.control.Label
        rcfNEFmsg                      matlab.ui.control.Label
        EphemerisfilesTextAreaLabel    matlab.ui.control.Label
        rcfEFL                         matlab.ui.control.TextArea
        rcfRun                         matlab.ui.control.Button
        pra                            matlab.ui.container.Tab
        pra_tabs                       matlab.ui.container.TabGroup
        praSDDtab                      matlab.ui.container.Tab
        SatellitesLabel                matlab.ui.control.Label
        praSLBox                       matlab.ui.control.ListBox
        SNRtypesListBoxLabel           matlab.ui.control.Label
        praSNRLBox                     matlab.ui.control.ListBox
        PlotdataButtonGroup            matlab.ui.container.ButtonGroup
        SNRButton                      matlab.ui.control.RadioButton
        dSNRButton                     matlab.ui.control.RadioButton
        praSDU                         matlab.ui.container.ButtonGroup
        SDUdB                          matlab.ui.control.RadioButton
        SDUVV                          matlab.ui.control.RadioButton
        praTU                          matlab.ui.container.ButtonGroup
        TUH                            matlab.ui.control.RadioButton
        TUSec                          matlab.ui.control.RadioButton
        AnglelimitationPanel           matlab.ui.container.Panel
        minEL                          matlab.ui.control.NumericEditField
        maxEL                          matlab.ui.control.NumericEditField
        ElevationangleLabel            matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        maxAZ                          matlab.ui.control.NumericEditField
        AzimuthangleLabel              matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        minAZ                          matlab.ui.control.NumericEditField
        SatellitetrackPoldegreePanel   matlab.ui.container.Panel
        TracknoLabel                   matlab.ui.control.Label
        TracknoSpinner                 matlab.ui.control.Spinner
        PolynomialdegreeSpinnerLabel   matlab.ui.control.Label
        PolynomialdegreeSpinner        matlab.ui.control.Spinner
        SDAxes                         matlab.ui.control.UIAxes
        SDAxesExpPlot                  matlab.ui.control.Button
        praSVtab                       matlab.ui.container.Tab
        SkyplotPanel                   matlab.ui.container.Panel
        SatellitesLabel_2              matlab.ui.control.Label
        praSLBox_2                     matlab.ui.control.ListBox
        SatellitesPanel                matlab.ui.container.Panel
        GPSCheckBox                    matlab.ui.control.CheckBox
        GLONASSCheckBox                matlab.ui.control.CheckBox
        GALILEOCheckBox                matlab.ui.control.CheckBox
        BEIDOUCheckBox                 matlab.ui.control.CheckBox
        SDAxesExpPlot_2                matlab.ui.control.Button
        praSAtab                       matlab.ui.container.Tab
        ParametersPanel                matlab.ui.container.Panel
        ReflectorheightEditFieldLabel  matlab.ui.control.Label
        ReflectorheightEditField       matlab.ui.control.NumericEditField
        SNRdataDropDown_2              matlab.ui.control.DropDown
        DataLabel                      matlab.ui.control.Label
        ElevationangleEditFieldLabel   matlab.ui.control.Label
        ElevationangleEditField        matlab.ui.control.NumericEditField
        FFZplotPanel                   matlab.ui.container.Panel
        FFZAxes                        matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        NumberofsatellitetracksEditFieldLabel  matlab.ui.control.Label
        NumberofsatellitetracksEditField  matlab.ui.control.NumericEditField
        AreaofanFFZellipsem2EditFieldLabel  matlab.ui.control.Label
        AreaofanFFZellipsem2EditField  matlab.ui.control.NumericEditField
        TotalFFZaream2EditFieldLabel   matlab.ui.control.Label
        TotalFFZaream2EditField        matlab.ui.control.NumericEditField
        DistancefromellipsecenterEditFieldLabel  matlab.ui.control.Label
        DistancefromellipsecenterEditField  matlab.ui.control.NumericEditField
        SDAxesExpPlot_3                matlab.ui.control.Button
        SDAxesExpPlot_4                matlab.ui.control.Button
        SDAxesExpPlot_5                matlab.ui.control.Button
        praSMBrowse                    matlab.ui.control.Button
        SNRMATfileLabel                matlab.ui.control.Label
        praSMfile                      matlab.ui.control.EditField
        AnalysisTab                    matlab.ui.container.Tab
        TabGroup                       matlab.ui.container.TabGroup
        MakeestimationsTab             matlab.ui.container.Tab
        SettingsPanel_2                matlab.ui.container.Panel
        SatellitesystemsPanel_2        matlab.ui.container.Panel
        GPSCheckBox_2                  matlab.ui.control.CheckBox
        GLONASSCheckBox_2              matlab.ui.control.CheckBox
        GALILEOCheckBox_2              matlab.ui.control.CheckBox
        BEIDOUCheckBox_2               matlab.ui.control.CheckBox
        SNRdataPanel                   matlab.ui.container.Panel
        SNRdataDropDown                matlab.ui.control.DropDown
        AnglelimitationPanel_2         matlab.ui.container.Panel
        ElevationEditFieldLabel        matlab.ui.control.Label
        ElevationEditField             matlab.ui.control.EditField
        AzimuthEditFieldLabel          matlab.ui.control.Label
        AzimuthEditField               matlab.ui.control.EditField
        PoldegPanel                    matlab.ui.container.Panel
        PolynomialdegreeSpinner_2      matlab.ui.control.Spinner
        EstimationsettingsPanel        matlab.ui.container.Panel
        MaximumreflectorheightmEditFieldLabel  matlab.ui.control.Label
        MaximumreflectorheightmEditField  matlab.ui.control.NumericEditField
        DesiredprecisionmEditFieldLabel  matlab.ui.control.Label
        DesiredprecisionmEditField     matlab.ui.control.NumericEditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
        anaFiles                       matlab.ui.container.Panel
        SNRMATfilesLabel               matlab.ui.control.Label
        anaSFL                         matlab.ui.control.TextArea
        anaSFBrowse                    matlab.ui.control.Button
        anaSFClear                     matlab.ui.control.Button
        anaNSFmsg                      matlab.ui.control.Label
        rcfRun_2                       matlab.ui.control.Button
        ProcessingstatusLabel          matlab.ui.control.Label
        GIRASStatus_2                  matlab.ui.control.TextArea
        ImproveestimationsTab          matlab.ui.container.Tab
        praSMBrowse_2                  matlab.ui.control.Button
        EstimationfileLabel            matlab.ui.control.Label
        praSMfile_2                    matlab.ui.control.EditField
        SettingsPanel                  matlab.ui.container.Panel
        QualityanalysisPanel           matlab.ui.container.Panel
        MADcb                          matlab.ui.control.CheckBox
        BNCcb                          matlab.ui.control.CheckBox
        MERcb                          matlab.ui.control.CheckBox
        MADco                          matlab.ui.control.NumericEditField
        BNCco                          matlab.ui.control.NumericEditField
        MERco                          matlab.ui.control.NumericEditField
        ButtonGroup                    matlab.ui.container.ButtonGroup
        DailydataButton                matlab.ui.control.RadioButton
        AlldataButton                  matlab.ui.control.RadioButton
        ApplytoLabel                   matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        SeeresultsButton               matlab.ui.control.Button
        MinMaxfrequencyEditFieldLabel  matlab.ui.control.Label
        MinMaxfrequencyEditField       matlab.ui.control.EditField
        PlottingPanel                  matlab.ui.container.Panel
        PlotresultsButton              matlab.ui.control.Button
        XDataDropDownLabel             matlab.ui.control.Label
        XDataDropDown                  matlab.ui.control.DropDown
        YDataDropDownLabel             matlab.ui.control.Label
        YDataDropDown                  matlab.ui.control.DropDown
        ResultsPanel                   matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        SaveresultsPanel               matlab.ui.container.Panel
        FilenameEditField_2Label       matlab.ui.control.Label
        FilenameEditField_2            matlab.ui.control.EditField
        SaveButton                     matlab.ui.control.Button
        AboutTab                       matlab.ui.container.Tab
        AuthorsPanel                   matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
        ContactPanel                   matlab.ui.container.Panel
        TextArea_2                     matlab.ui.control.TextArea
        CiteasPanel                    matlab.ui.container.Panel
        TextArea_3                     matlab.ui.control.TextArea
        StatusTextAreaLabel            matlab.ui.control.Label
        GIRASStatus                    matlab.ui.control.TextArea
        GIRASLabel                     matlab.ui.control.Label
    end

    
    methods (Access = private)
        % FUNCTION: Get SNR data function
        function [snrdata,dataepochs,dataELAZ,unitSD,unitT] = getSNRdata(app,pra,snrmat,satname,snrname)
            sat_index = find_sat_index(satname);
            snr_index = find_snr_index(snrmat,snrname);
            % Data
            snrdata = snrmat.snrdata{1,sat_index}(snrmat.ELAZ{sat_index}(:,1) > pra.angEL(1) & ...
                snrmat.ELAZ{sat_index}(:,1) < pra.angEL(2) & snrmat.ELAZ{sat_index}(:,2) > pra.angAZ(1) & ...
                snrmat.ELAZ{sat_index}(:,2) < pra.angAZ(2),snr_index);
            dataepochs = snrmat.info.epochs(snrmat.ELAZ{sat_index}(:,1) > pra.angEL(1) & ...
                snrmat.ELAZ{sat_index}(:,1) < pra.angEL(2) & snrmat.ELAZ{sat_index}(:,2) > pra.angAZ(1) & ...
                snrmat.ELAZ{sat_index}(:,2) < pra.angAZ(2));
            dataELAZ = snrmat.ELAZ{sat_index}(snrmat.ELAZ{sat_index}(:,1) > pra.angEL(1) & ...
                snrmat.ELAZ{sat_index}(:,1) < pra.angEL(2) & snrmat.ELAZ{sat_index}(:,2) > pra.angAZ(1) & ...
                snrmat.ELAZ{sat_index}(:,2) < pra.angAZ(2),:);
            % Settings
            if pra.SDU == 1
                unitSD = '(dB)';
            else
                snrdata = 10.^(snrdata./20);
                unitSD = '(Volts/Volts)';
            end
            if pra.TU == 1
                dataepochs = dataepochs/3600;
                unitT = '(hours)';
            else
                unitT = '(seconds)';
            end
        end
        
        % FUNCTION: Plot SNR function
        function plotSNR(app,pra,snrmat,satname,snrname)
            % Data
            [snrdata,dataepochs,dataELAZ,unitSD,unitT] = getSNRdata(app,pra,snrmat,satname,snrname);
            % Plot
            yyaxis(app.SDAxes,'left'); plot(app.SDAxes,dataepochs,snrdata,'Color',[0 114 178]./255);
            app.SDAxes.YAxis(1).Color = [0 114 178]./255;
            app.SDAxes.XLabel.String = ['Epoch ',unitT];
            app.SDAxes.YLabel.String = ['SNR ',unitSD];
            app.SDAxes.Title.String = [satname,' ',snrname,' (EL: ',num2str(min(dataELAZ(:,1)),'%.f'),'\circ-',num2str(max(dataELAZ(:,1)),'%.f'),'\circ; AZ:',num2str(min(dataELAZ(:,2)),'%.f'),'\circ-',num2str(max(dataELAZ(:,2)),'%.f'),'\circ)'];
            try
                app.SDAxes.XLim = [min(dataepochs) max(dataepochs)];
                app.SDAxes.YLim = [min(snrdata) max(snrdata)];
            catch
            end
            yyaxis(app.SDAxes,'right')
            plot(app.SDAxes,dataepochs,dataELAZ(:,1),'Color',[213 94 0]./255);
            app.SDAxes.YAxis(2).Color = [213 94 0]./255;
            app.SDAxes.YLabel.String = 'Elevation angle (\circ)';
            app.SDAxes.YAxis(2).Visible = 'on';
            legend(app.SDAxes,'hide')
        end
        
        % FUNCTION: Export SNR plot
        function exportSNRplot(app,pra,snrmat,satname,snrname)
            % Data
            [snrdata,dataepochs,dataELAZ,unitSD,unitT] = getSNRdata(app,pra,snrmat,satname,snrname);
            % Plot
            figure('Name','SNR plot','Color','White');
            yyaxis(gca,'left'); plot(gca,dataepochs,snrdata,'Color',[0 114 178]./255);
            set(gca,'YColor',[0 114 178]./255);
            xlabel(gca,['Epoch ',unitT]); ylabel(gca,['SNR ',unitSD]);
            title(gca,[satname,' ',snrname,' (EL: ',num2str(min(dataELAZ(:,1)),'%.f'),'\circ-',num2str(max(dataELAZ(:,1)),'%.f'),'\circ; AZ:',num2str(min(dataELAZ(:,2)),'%.f'),'\circ-',num2str(max(dataELAZ(:,2)),'%.f'),'\circ)']);
            try
                xlim(gca,[min(dataepochs) max(dataepochs)]);
                ylim(gca,[min(snrdata) max(snrdata)]);
            catch
            end
            yyaxis(gca,'right')
            plot(gca,dataepochs,dataELAZ(:,1),'Color',[213 94 0]./255);
            set(gca,'YColor',[213 94 0]./255);
            ylabel(gca,'Elevation angle (\circ)');
        end
      
        % FUNCTION: Plot dSNR function
        function plotdSNR(app,pra,dSNRdata,satname,snrname)
            % Data
            datadSNR = dSNRdata{1,pra.ST};
            tracktype = dSNRdata{2,pra.ST};
            % Settings
            unitSD = '(Volts/Volts)';
            % Plot
            cla(app.SDAxes,'reset');
            plot(app.SDAxes,datadSNR(:,2),datadSNR(:,4),'-','LineWidth',0.7,'Color',[0 114 178]./255);
            hold(app.SDAxes,'on'); plot(app.SDAxes,datadSNR(:,2),datadSNR(:,5),'-','LineWidth',0.9,'Color','r');
            plot(app.SDAxes,datadSNR(:,2),datadSNR(:,6),'-','LineWidth',0.7,'Color','k'); hold(app.SDAxes,'off');
            legend(app.SDAxes,'SNR','Model','dSNR','Location','northwest','NumColumns',3);
            app.SDAxes.YAxis(1).Color = 'k';
            app.SDAxes.XLabel.String = 'Elevation angle (\circ)';
            app.SDAxes.YLabel.String = ['Detrended SNR ',unitSD];
            app.SDAxes.Title.String = [satname,'-',tracktype,' ',snrname,' (EL: ',num2str(min(datadSNR(:,2)),...
                '%.f'),'\circ-',num2str(max(datadSNR(:,2)),'%.f'),'\circ; AZ:',num2str(min(datadSNR(:,3)),'%.f'),...
                '\circ-',num2str(max(datadSNR(:,3)),'%.f'),'\circ)'];
            try
                app.SDAxes.XLim = [min(datadSNR(:,2)) max(datadSNR(:,2))];
                app.SDAxes.YLim = [min(datadSNR(:,4:6)) max(datadSNR(:,4:6))];
            catch
            end
            grid(app.SDAxes,'on'); box(app.SDAxes,'on');
        end
        
        % FUNCTION: Export dSNR plot
        function exportdSNRplot(app,pra,dSNRdata,satname,snrname)
            % Data
            datadSNR = dSNRdata{1,pra.ST};
            tracktype = dSNRdata{2,pra.ST};
            % Settings
            unitSD = '(Volts/Volts)';
            % Plot
            figure('Name','dSNR plot','Color','White');
            plot(gca,datadSNR(:,2),datadSNR(:,4),'-','LineWidth',0.7,'Color',[0 114 178]./255);
            hold(gca,'on'); plot(gca,datadSNR(:,2),datadSNR(:,5),'-','LineWidth',0.9,'Color','r');
            plot(gca,datadSNR(:,2),datadSNR(:,6),'-','LineWidth',0.7,'Color','k'); hold(gca,'off');
            legend(gca,'SNR','Model','dSNR','Location','northwest','NumColumns',3);
            xlabel(gca,'Elevation angle (\circ)'); ylabel(gca,['Detrended SNR ',unitSD]);
            title(gca,[satname,'-',tracktype,' ',snrname,' (EL: ',num2str(min(datadSNR(:,2)),'%.f'),...
                '\circ-',num2str(max(datadSNR(:,2)),'%.f'),'\circ; AZ:',num2str(min(datadSNR(:,3)),'%.f'),...
                '\circ-',num2str(max(datadSNR(:,3)),'%.f'),'\circ)']);
            try
                xlim(gca,[min(datadSNR(:,2)) max(datadSNR(:,2))]);
                ylim(gca,[min(datadSNR(:,4:6)) max(datadSNR(:,4:6))]);
            catch
            end
            grid(gca,'off'); box(gca,'on');
        end
    
        % FUNCTION: Get XY data
        function [Xlabel,Xdata,Ylabel,Ydata] = getXYdata(app,Xdatatype,Ydatatype,goodresults)
            key = 0;
            years = unique(cell2mat(goodresults(:,1)));
            days = unique(cell2mat(goodresults(:,2)));
            switch Ydatatype
                case 'Frequency'
                    Ylabel = 'Frequency';
                    Ydata = cell2mat(goodresults(:,7));
                case 'Amplitude'
                    Ylabel = 'Amplitude';
                    Ydata = cell2mat(goodresults(:,8));
                case 'Phase'
                    Ylabel = 'Phase';
                    Ydata = cell2mat(goodresults(:,9));
                case 'Frequency (daily mean)'
                    Ylabel = 'Frequency (daily mean)';
                    key = 1; Ydata = [];
                    Ydata0 = cell2mat(goodresults(:,7));
                    for i = 1:length(years)
                        for j = 1:length(days)
                            Ydata = [Ydata; mean(Ydata0(cell2mat(goodresults(:,1)) == years(i) & ...
                                cell2mat(goodresults(:,2)) == days(j)))];
                        end
                    end
                case 'Amplitude (daily mean)'
                    Ylabel = 'Amplitude (daily mean)';
                    key = 1; Ydata = [];
                    Ydata0 = cell2mat(goodresults(:,8));
                    for i = 1:length(years)
                        for j = 1:length(days)
                            Ydata = [Ydata; mean(Ydata0(cell2mat(goodresults(:,1)) == years(i) & ...
                                cell2mat(goodresults(:,2)) == days(j)))];
                        end
                    end
                case 'Phase (daily mean)'
                    Ylabel = 'Phase (daily mean)';
                    key = 1; Ydata = [];
                    Ydata0 = cell2mat(goodresults(:,9));
                    for i = 1:length(years)
                        for j = 1:length(days)
                            Ydata = [Ydata; mean(Ydata0(cell2mat(goodresults(:,1)) == years(i) & ...
                                cell2mat(goodresults(:,2)) == days(j)))];
                        end
                    end
            end
            switch Xdatatype
                case 'Epoch'
                    Xlabel = 'Epoch';
                    if key == 1
                        Xdata = [];
                        Xdata0 = cell2mat(goodresults(:,[1 2 10 11]));
                        for i = 1:length(years)
                            for j = 1:length(days)
                                Xdate = mean(Xdata0(cell2mat(goodresults(:,1)) == years(i) & ...
                                    cell2mat(goodresults(:,2)) == days(j),:),1);
                                Xdata = [Xdata; datetime(Xdate(1),1,1)+(Xdate(2)-1)];
                            end
                        end
                    else
                        Xdata = datetime(cell2mat(goodresults(1,1)),1,1)+(cell2mat(goodresults(:,2))-1)+...
                            (cell2mat(goodresults(:,10))+cell2mat(goodresults(:,11)))/(2*86400);
                    end
                case 'Mean elevation angle'
                    Xlabel = 'Mean elevation angle (\circ)';
                    Xdata = (cell2mat(goodresults(:,13))+cell2mat(goodresults(:,14)))/2;
                case 'Mean azimuth angle'
                    Xlabel = 'Mean azimuth angle (\circ)';
                    Xdata = (cell2mat(goodresults(:,15))+cell2mat(goodresults(:,16)))/2;
                case 'BNC ratio'
                    Xlabel = 'BNC ratio';
                    Xdata = cell2mat(goodresults(:,17))./cell2mat(goodresults(:,18));
                case 'Elevation angle range'
                    Xlabel = 'Elevation angle range (\circ)';
                    Xdata = cell2mat(goodresults(:,14))-cell2mat(goodresults(:,13));
                case 'Number of epochs'
                    Xlabel = 'Number of epochs';
                    Xdata = cell2mat(goodresults(:,12));
                case 'Number of epochs per sin(elevation angle)'
                    Xlabel = 'Number of epochs per sin(\epsilon)';
                    Xdata = cell2mat(goodresults(:,12))./(sind(cell2mat(goodresults(:,14)))-sind(cell2mat(goodresults(:,13))));
            end
        end

        % FUNCTION: Set XY data
        function setXYdata(app,Xdatatype,Ydatatype)
            if strcmp(Ydatatype(end-4:end),'mean)')
                app.XDataDropDown.Items = {'Epoch'};
            else
                app.XDataDropDown.Items = {'Epoch','Mean elevation angle','Mean azimuth angle','BNC ratio','Elevation angle range',...
                    'Number of epochs','Number of epochs per sin(elevation angle)'};
            end
            if strcmp(Xdatatype,'Epoch')
                app.YDataDropDown.Items = {'Frequency','Amplitude','Phase','Frequency (daily mean)','Amplitude (daily mean)','Phase (daily mean)'};
            else
                app.YDataDropDown.Items = {'Frequency','Amplitude','Phase'};
            end
        end
    
        % FUNCTION: Plot sky view
        function plotskyview(app, satellites, selectedsat, snrmat)
            pax = polaraxes(app.SkyplotPanel);
            for i = 1:length(satellites)
                sat_index = find_sat_index(satellites{i});
                if strcmp(satellites{i},selectedsat)
                    polarplot(pax,deg2rad(snrmat.ELAZ{1,sat_index}(:,2)),snrmat.ELAZ{1,sat_index}(:,1),'Color','k','LineWidth',4);
                elseif sat_index < 101
                    polarplot(pax,deg2rad(snrmat.ELAZ{1,sat_index}(:,2)),snrmat.ELAZ{1,sat_index}(:,1),'Color','r');
                elseif sat_index > 100 && sat_index < 201
                    polarplot(pax,deg2rad(snrmat.ELAZ{1,sat_index}(:,2)),snrmat.ELAZ{1,sat_index}(:,1),'Color',[0.27,0.72,0.04]);
                elseif sat_index > 200 && sat_index < 301
                    polarplot(pax,deg2rad(snrmat.ELAZ{1,sat_index}(:,2)),snrmat.ELAZ{1,sat_index}(:,1),'Color','b');
                else
                    polarplot(pax,deg2rad(snrmat.ELAZ{1,sat_index}(:,2)),snrmat.ELAZ{1,sat_index}(:,1),'Color','k');
                end
                hold(pax,'on');
            end
            hold(pax,'off');
            pax.ThetaDir = 'clockwise';
            pax.RDir = 'reverse';
            pax.ThetaZeroLocation = 'top';
            rlim(pax,[0 90])
        end
    
        % FUNCTION: Export sky view plot
        function exportskyviewplot(app, satellites, selectedsat, snrmat)
            pax = polaraxes;
            % Legend
            hold(pax,'on');
            polarplot(pax,NaN,NaN,'Color','r','LineWidth',1);
            polarplot(pax,NaN,NaN,'Color',[0.27,0.72,0.04],'LineWidth',1);
            polarplot(pax,NaN,NaN,'Color','b','LineWidth',1);
            polarplot(pax,NaN,NaN,'Color','k','LineWidth',1);
            % Plotting
            for i = 1:length(satellites)
                sat_index = find_sat_index(satellites{i});
                if sat_index < 101
                    polarplot(pax,deg2rad(snrmat.ELAZ{1,sat_index}(:,2)),snrmat.ELAZ{1,sat_index}(:,1),'Color','r','LineWidth',0.8);
                elseif sat_index > 100 && sat_index < 201
                    polarplot(pax,deg2rad(snrmat.ELAZ{1,sat_index}(:,2)),snrmat.ELAZ{1,sat_index}(:,1),'Color',[0.27,0.72,0.04],'LineWidth',0.8);
                elseif sat_index > 200 && sat_index < 301
                    polarplot(pax,deg2rad(snrmat.ELAZ{1,sat_index}(:,2)),snrmat.ELAZ{1,sat_index}(:,1),'Color','b','LineWidth',0.8);
                else
                    polarplot(pax,deg2rad(snrmat.ELAZ{1,sat_index}(:,2)),snrmat.ELAZ{1,sat_index}(:,1),'Color','k','LineWidth',0.8);
                end
            end
            hold(pax,'off');
            pax.ThetaDir = 'clockwise';
            pax.RDir = 'reverse';
            pax.ThetaZeroLocation = 'top';
            rlim(pax,[0 90]);
            legend({'GPS','GLO','GAL','BDS'});
        end
    
        % FUNCTION: Plot FFZ
        function plotFFZ(app, snrmat, n0e0u0, nPePuP, TN)
            f = waitbar(0,'Please wait...','Name','Plot FFZ');
            cla(app.FFZAxes,'reset');
            app.FFZAxes.XLimMode = 'auto';
            plot(app.FFZAxes,0,0,'^','MarkerFaceColor','k')
            hold(app.FFZAxes,'on')
            trackno = 0;
            for i = 1:length(snrmat.info.observedsats)
                sat_index = find_sat_index(snrmat.info.observedsats{i});
                for j = 1:(length(n0e0u0{i}))
                    if ~isempty(n0e0u0{i}{j})
                        if ~isempty(nPePuP{i}{j}(~isnan(nPePuP{i}{j}(:,1)),1)); trackno = trackno + 1; waitbar(trackno/TN,f,'Preparing...'); end
                        if sat_index < 101
                            plot(app.FFZAxes,n0e0u0{i}{j}(2),n0e0u0{i}{j}(1),'.','Color','r')
                            scatter(app.FFZAxes,nPePuP{i}{j}(:,2),nPePuP{i}{j}(:,1),0.1,'.','MarkerFaceColor','r','MarkerEdgeColor','r')
                        elseif sat_index > 100 && sat_index < 201
                            plot(app.FFZAxes,n0e0u0{i}{j}(2),n0e0u0{i}{j}(1),'.','Color',[0.27,0.72,0.04])
                            scatter(app.FFZAxes,nPePuP{i}{j}(:,2),nPePuP{i}{j}(:,1),0.1,'.','MarkerFaceColor',[0.27,0.72,0.04],'MarkerEdgeColor',[0.27,0.72,0.04])
                        elseif sat_index > 200 && sat_index < 301
                            plot(app.FFZAxes,n0e0u0{i}{j}(2),n0e0u0{i}{j}(1),'.','Color','b')
                            scatter(app.FFZAxes,nPePuP{i}{j}(:,2),nPePuP{i}{j}(:,1),0.1,'.','MarkerFaceColor','b','MarkerEdgeColor','b')
                        else
                            plot(app.FFZAxes,n0e0u0{i}{j}(2),n0e0u0{i}{j}(1),'.','Color','k')
                            scatter(app.FFZAxes,nPePuP{i}{j}(:,2),nPePuP{i}{j}(:,1),0.1,'.','MarkerFaceColor','k','MarkerEdgeColor','k')
                        end
                    end
                end
            end
            app.NumberofsatellitetracksEditField.Value = trackno;
            hold(app.FFZAxes,'off')
            axlims = [min([app.FFZAxes.XLim app.FFZAxes.YLim]) max([app.FFZAxes.XLim app.FFZAxes.YLim])];
            app.FFZAxes.XLim = axlims; app.FFZAxes.YLim = axlims;
            grid(app.FFZAxes,'on'); box(app.FFZAxes,'on');
            app.FFZAxes.XLabel.String = 'East (m)';
            app.FFZAxes.YLabel.String = 'North (m)';
            close(f);
        end
    
        % FUNCTION: Export FFZ plot
        function exportFFZplot(app, snrmat, n0e0u0, nPePuP, TN)
            f = waitbar(0,'Please wait...','Name','Export FFZ plot');
            figure('Name','FFZ plot','Color','White');
            % Legend
            hold(gca,'on');
            plot(gca,0,0,'^','MarkerFaceColor','k')
            plot(gca,NaN,NaN,'Color','r','LineWidth',1);
            plot(gca,NaN,NaN,'Color',[0.27,0.72,0.04],'LineWidth',1);
            plot(gca,NaN,NaN,'Color','b','LineWidth',1);
            plot(gca,NaN,NaN,'Color','k','LineWidth',1);
            % Plotting
            trackno = 0;
            for i = 1:length(snrmat.info.observedsats)
                sat_index = find_sat_index(snrmat.info.observedsats{i});
                for j = 1:(length(n0e0u0{i}))
                    if ~isempty(n0e0u0{i}{j})
                        if ~isempty(nPePuP{i}{j}(~isnan(nPePuP{i}{j}(:,1)),1)); trackno = trackno + 1; waitbar(trackno/TN,f,'Preparing...'); end
                        if sat_index < 101
                            plot(gca,n0e0u0{i}{j}(2),n0e0u0{i}{j}(1),'.','Color','r')
                            scatter(gca,nPePuP{i}{j}(:,2),nPePuP{i}{j}(:,1),0.1,'.','MarkerFaceColor','r','MarkerEdgeColor','r')
                        elseif sat_index > 100 && sat_index < 201
                            plot(gca,n0e0u0{i}{j}(2),n0e0u0{i}{j}(1),'.','Color',[0.27,0.72,0.04])
                            scatter(gca,nPePuP{i}{j}(:,2),nPePuP{i}{j}(:,1),0.1,'.','MarkerFaceColor',[0.27,0.72,0.04],'MarkerEdgeColor',[0.27,0.72,0.04])
                        elseif sat_index > 200 && sat_index < 301
                            plot(gca,n0e0u0{i}{j}(2),n0e0u0{i}{j}(1),'.','Color','b')
                            scatter(gca,nPePuP{i}{j}(:,2),nPePuP{i}{j}(:,1),0.1,'.','MarkerFaceColor','b','MarkerEdgeColor','b')
                        else
                            plot(gca,n0e0u0{i}{j}(2),n0e0u0{i}{j}(1),'.','Color','k')
                            scatter(gca,nPePuP{i}{j}(:,2),nPePuP{i}{j}(:,1),0.1,'.','MarkerFaceColor','k','MarkerEdgeColor','k')
                        end
                    end
                end
            end
            hold(gca,'off')
            axg = gca;
            axlims = [min([axg.XLim axg.YLim]) max([axg.XLim axg.YLim])];
            axg.XLim = axlims; axg.YLim = axlims;
            grid(gca,'on'); box(gca,'on');
            axg.XLabel.String = 'East (m)';
            axg.YLabel.String = 'North (m)';
            legend({'Site','GPS','GLO','GAL','BDS'});
            close(f);
        end
    
        % FUNCTION: Export KML
        function exportFFZKML(app, snrmat, el_LLH, el_LLH_kmlcode, filename)
            warning('off','all')
            % Inputs
            stationname = snrmat.info.markername;
            [latp, lonp, hp] = xyz2ell(snrmat.info.sitepos(1),snrmat.info.sitepos(2),snrmat.info.sitepos(3));
            % Create KML file
            mkdir('FFZ',stationname);
            directory = strcat('FFZ','/',stationname,'/');
            fid = fopen(strcat(directory,filename,'.kml'),'w');
            fprintf(fid, '<?xml version="1.0" encoding="UTF-8"?>\n');
            fprintf(fid, ['<kml xmlns="http://www.opengis.net/kml/2.2" xmlns:gx="http://',...
                'www.google.com/kml/ext/2.2" xmlns:kml="http://www.opengis.net/kml/2.2" ',...
                'xmlns:atom="http://www.w3.org/2005/Atom">\n']);
            fprintf(fid, '<Document>\n');
            fprintf(fid, ['	<name>',filename,'.kml','</name>\n']);
            fprintf(fid, ['	<open>1</open>\n	<StyleMap id="msn_ylw-pushpin">\n',...
                '		<Pair>\n			<key>normal</key>\n			<styleUrl>#sn_ylw-pushpin</styleUrl>\n',...
                '		</Pair>\n		<Pair>\n			<key>highlight</key>\n',...
                '			<styleUrl>#sh_ylw-pushpin</styleUrl>\n		</Pair>\n',...
                '	</StyleMap>\n	<Style id="sn_ylw-pushpin">\n		<IconStyle>\n',...
                '			<scale>1.1</scale>\n			<Icon>\n',...
                '				<href>http://maps.google.com/mapfiles/kml/pushpin/ylw-pushpin.png</href>\n',...
                '			</Icon>\n			<hotSpot x="20" y="2" xunits="pixels" yunits="pixels"/>\n',...
                '		</IconStyle>\n		<LineStyle>\n			<color>ff7fffff</color>\n			<width>3</width>',...
                '		</LineStyle>\n		<PolyStyle>\n			<color>00ffffff</color>\n',...
                '		</PolyStyle>\n	</Style>\n','	<Style id="sh_ylw-pushpin">\n',...
                '		<IconStyle>\n			<scale>1.3</scale>\n			<Icon>\n',...
                '				<href>http://maps.google.com/mapfiles/kml/pushpin/ylw-pushpin.png</href>\n',...
                '			</Icon>\n			<hotSpot x="20" y="2" xunits="pixels" yunits="pixels"/>\n',...
                '		</IconStyle>\n		<LineStyle>\n			<color>ff0000ff</color>\n			<width>6</width>',...
                '		</LineStyle>\n		<PolyStyle>\n			<color>ff0000ff</color>\n',...
                '		</PolyStyle>\n','	</Style>\n']);
            fprintf(fid, ['	<StyleMap id="msn_triangle">\n		<Pair>\n			<key>normal</key>\n',...
                '			<styleUrl>#sn_triangle</styleUrl>\n		</Pair>\n		<Pair>\n',...
                '			<key>highlight</key>\n			<styleUrl>#sh_triangle</styleUrl>\n',...
                '		</Pair>\n	</StyleMap>\n	<Style id="sh_triangle">\n		<IconStyle>\n',...
                '			<color>ff0000ff</color>\n			<scale>1.4</scale>\n',...
                '			<Icon>\n				<href>http://maps.google.com/mapfiles/kml/shapes/placemark_circle_highlight.png</href>\n',...
                '			</Icon>\n		</IconStyle>\n		<BalloonStyle>\n		</BalloonStyle>\n',...
                '		<ListStyle>\n		</ListStyle>\n	</Style>\n	<Style id="sn_triangle">\n',...
                '		<IconStyle>\n			<color>ff0000ff</color>\n			<scale>1.2</scale>\n',...
                '			<Icon>\n				<href>http://maps.google.com/mapfiles/kml/shapes/placemark_circle.png</href>\n',...
                '			</Icon>\n		</IconStyle>\n		<BalloonStyle>\n		</BalloonStyle>\n',...
                '		<ListStyle>\n		</ListStyle>\n	</Style>\n']);
            % Station
            fprintf(fid, ['	<Placemark>\n		<name>',stationname,'</name>\n		<LookAt>\n',...
                '			<longitude>',num2str(lonp*180/pi,'%1.13f'),'</longitude>\n',...
                '			<latitude>',num2str(latp*180/pi,'%1.13f'),'</latitude>\n',...
                '			<altitude>',num2str(hp,'%1.13f'),'</altitude>\n',...
                '		</LookAt>\n		<styleUrl>#msn_triangle</styleUrl>\n		<Point>\n',...
                '			<gx:drawOrder>1</gx:drawOrder>\n',...
                '			<coordinates>',num2str(lonp*180/pi,'%1.13f'),',',...
                num2str(latp*180/pi,'%1.13f'),',',...
                num2str(hp),'</coordinates>\n		</Point>\n	</Placemark>\n']);
            % FFZ ellipses
            for i = 1:length(snrmat.info.observedsats)
                for j = 1:length(el_LLH{i})
                    if ~isempty(el_LLH{i}{j})
                        if ~isempty(el_LLH{i}{j}(~isnan(el_LLH{i}{j}(:,1)),1))
                            fprintf(fid, ['	<Placemark>\n','		<name>',snrmat.info.observedsats{i},'_',num2str(j),...
                            '</name>\n		<open>1</open>\n',...
                            '		<styleUrl>#msn_ylw-pushpin</styleUrl>\n		<Polygon>\n',...
                            '			<tessellate>1</tessellate>\n			<outerBoundaryIs>\n',...
                            '				<LinearRing>\n					<coordinates>\n',...
                            el_LLH_kmlcode{i}{j},'\n					</coordinates>\n',...
                            '				</LinearRing>\n			</outerBoundaryIs>\n',...
                            '		</Polygon>\n	</Placemark>\n']);
                        end
                    end
                end
            end
            fprintf(fid, '</Document>\n</kml>\n');
            fclose(fid);
        end
    
    end
    
    methods (Access = public)
        
        % FUNCTION: Write results to mat file
        function resultsfile = writeresultstomatfile(app,txtfile)
            % (1) Read file
            [fID] = fopen(txtfile, 'r');
            if fID < 0
                error('Unable to open file named %s.\n\n', filename);
            end
            % > Line by line reading
            t = struct('line',{});
            iline = 0;
            lines = fgets(fID);
            while ischar(lines)
                iline = iline + 1;
                t(iline).line = lines;
                if strcmp(cellstr(t(iline).line(61:length(t(iline).line))),...
                        'END OF HEADER')
                    lastheaderline = iline;
                end
                lines = fgets(fID);
            end
            fclose(fID);
            
            % (2) Write results to the variable named resultsfile
            resultsfile = cell(iline,17);
            for rownum = 1:iline
                resultsfile{rownum,1}  = str2double(t(rownum).line(1:4));     % Column 1  - Year
                resultsfile{rownum,2}  = str2double(t(rownum).line(6:8));     % Column 2  - Day of year
                resultsfile{rownum,3}  = strtrim(t(rownum).line(10:14));      % Column 3  - Satellite PRN
                resultsfile{rownum,4}  = strtrim(t(rownum).line(16:20));      % Column 4  - SNR type
                resultsfile{rownum,5}  = str2double(t(rownum).line(22:23));   % Column 5  - Satellite track no
                resultsfile{rownum,6}  = strtrim(t(rownum).line(25:29));      % Column 6  - Satellite track type
                resultsfile{rownum,7}  = str2double(t(rownum).line(31:40));   % Column 7  - Dominant frequency
                resultsfile{rownum,8}  = str2double(t(rownum).line(42:51));   % Column 8  - Dominant normalized amplitude
                resultsfile{rownum,9}  = str2double(t(rownum).line(53:62));   % Column 9  - Dominant phase
                resultsfile{rownum,10} = str2double(t(rownum).line(64:73));   % Column 10 - Min(t)
                resultsfile{rownum,11} = str2double(t(rownum).line(75:84));   % Column 11 - Max(t)
                resultsfile{rownum,12} = str2double(t(rownum).line(86:95));   % Column 12 - Number of epochs
                resultsfile{rownum,13} = str2double(t(rownum).line(97:106));  % Column 13 - Min(elev)
                resultsfile{rownum,14} = str2double(t(rownum).line(108:117)); % Column 14 - Max(elev)
                resultsfile{rownum,15} = str2double(t(rownum).line(119:128)); % Column 15 - Min(azim)
                resultsfile{rownum,16} = str2double(t(rownum).line(130:139)); % Column 16 - Max(azim)
                resultsfile{rownum,17} = str2double(t(rownum).line(141:150)); % Column 17 - Max(P)
                resultsfile{rownum,18} = str2double(t(rownum).line(152:end)); % Column 18 - Background noise
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % ADD ALL FOLDERS AND SUBFOLDERS TO MATLAB PATH
            main_folder = fileparts(which('GIRAS.mlapp'));
            addpath(genpath(main_folder));
            
            % OPEN SETTINGS DATA
            % (1) For read & convert files section
            global rcf
            load('settings/rcf.mat');
            % (2) For pre-analysis section
            global pra
            load('settings/pra.mat');
            % (3) For analysis section
            global ana
            load('settings/ana.mat');
            % (4) For analysis section - 2
            global ana2
            load('settings/ana2.mat');
            
        end

        % Selection changed function: rcfIT
        function rcfITSelectionChanged(app, event)
            % RCF/SETTINGS: Input type
            global rcf
            selectedButton = app.rcfIT.SelectedObject;
            if strcmp(selectedButton.Text,'Raw file')
                rcf.IT = 1;
                app.rcfET1.Enable = 'on'; app.rcfET2.Enable = 'on';
                app.rcfPT1.Enable = 'on'; app.rcfPT2.Enable = 'on';
                app.rcfSP1.Enable = 'off'; app.rcfSP2.Enable = 'off';
                app.rcfPT.Buttons(1).Value = 1;
                rcf.PT = 1;
            else
                rcf.IT = 2;
                app.rcfPT1.Enable = 'off'; app.rcfPT2.Enable = 'off';
                app.rcfSP1.Enable = 'on'; app.rcfSP2.Enable = 'on';
                app.rcfPT.Buttons(2).Value = 1;
                rcf.PT = 2;
            end
        end

        % Selection changed function: rcfSPset
        function rcfSPsetSelectionChanged(app, event)
            % RCF/SETTINGS: Station position (RADIO BUTTON)
            global rcf
            if app.rcfSPset.Buttons(2).Value
                app.rcfSPX.Enable = 'on'; app.rcfSPY.Enable = 'on'; app.rcfSPZ.Enable = 'on';
                rcf.XYZ = [app.rcfSPX.Value app.rcfSPY.Value app.rcfSPZ.Value];
                rcf.SP = 2;
            else
                app.rcfSPX.Enable = 'off'; app.rcfSPY.Enable = 'off'; app.rcfSPZ.Enable = 'off';
                rcf.XYZ = [NaN NaN NaN];
                rcf.SP = 1;
            end
        end

        % Value changed function: rcfSPX
        function rcfSPXValueChanged(app, event)
            % RCF/SETTINGS: Station position (X coordinate)
            global rcf
            rcf.XYZ(1) = app.rcfSPX.Value;
        end

        % Value changed function: rcfSPY
        function rcfSPYValueChanged(app, event)
            % RCF/SETTINGS: Station position (Y coordinate)
            global rcf
            rcf.XYZ(2) = app.rcfSPY.Value;
        end

        % Value changed function: rcfSPZ
        function rcfSPZValueChanged(app, event)
            % RCF/SETTINGS: Station position (Z coordinate)
            global rcf
            rcf.XYZ(3) = app.rcfSPZ.Value;
        end

        % Selection changed function: rcfPT
        function rcfPTSelectionChanged(app, event)
            % RCF/SETTINGS: Process type
            global rcf
            if app.rcfPT.Buttons(1).Value
                rcf.PT = 1;
                app.rcfSP1.Enable = 'off'; app.rcfSP2.Enable = 'off';
            else
                rcf.PT = 2;
                app.rcfSP1.Enable = 'on'; app.rcfSP2.Enable = 'on';
            end
        end

        % Selection changed function: rcfET
        function rcfETSelectionChanged(app, event)
            % RCF/SETTINGS: Ephemeris file
            global rcf
            if app.rcfET.Buttons(1).Value
                rcf.EFT = 1;
            else
                rcf.EFT = 2;
            end
        end

        % Button pushed function: rcfOFBrowse
        function rcfOFBrowseButtonPushed(app, event)
            % RCF/FILES: Observation files (BROWSE BUTTON)
            global rcf
            if rcf.IT == 1
                try [obsfiles, obspath] = uigetfile('data/inp_files/obs/*.*','Select observation files','MultiSelect','on');
                    addpath(genpath(obspath));
                    app.rcfOFL.Value = obsfiles;
                    if ischar(obsfiles); rcf.OFL = {obsfiles}; else; rcf.OFL = obsfiles'; end
                    rcf.NOF = length(rcf.OFL);
                    app.rcfNOFmsg.Text = sprintf('%1.0f observation files selected.',rcf.NOF);
                catch
                end
            else
                try [obsfiles, obspath] = uigetfile('data/mat_files/obs/*.*','Select OBSMAT files','MultiSelect','on');
                    addpath(genpath(obspath));
                    app.rcfOFL.Value = obsfiles;
                    if ischar(obsfiles); rcf.OFL = {obsfiles}; else; rcf.OFL = obsfiles'; end
                    rcf.NOF = length(rcf.OFL);
                    app.rcfNOFmsg.Text = sprintf('%1.0f observation files selected.',rcf.NOF);
                catch
                end
            end
        end

        % Button pushed function: rcfEFBrowse
        function rcfEFBrowseButtonPushed(app, event)
            % RCF/FILES: Ephemeris files (BROWSE BUTTON)
            global rcf
            if rcf.IT == 1
                if rcf.EFT == 1
                    try [navfiles, navpath] = uigetfile('data/inp_files/nav/*.*','Select ephemeris files','MultiSelect','on');
                        addpath(genpath(navpath));
                        app.rcfEFL.Value = navfiles;
                        if ischar(navfiles); rcf.EFL = {navfiles}; else; rcf.EFL = navfiles'; end
                        rcf.NEF = length(rcf.EFL);
                        app.rcfNEFmsg.Text = sprintf('%1.0f ephemeris files selected.',rcf.NEF);
                    catch
                    end
                else
                    try [ephfiles, ephpath] = uigetfile('data/inp_files/eph/*.*','Select ephemeris files','MultiSelect','on');
                        addpath(genpath(ephpath));
                        app.rcfEFL.Value = ephfiles;
                        if ischar(ephfiles); rcf.EFL = {ephfiles}; else; rcf.EFL = ephfiles'; end
                        rcf.NEF = length(rcf.EFL);
                        app.rcfNEFmsg.Text = sprintf('%1.0f ephemeris files selected.',rcf.NEF);
                    catch
                    end
                end
            else
                if rcf.EFT == 1
                    try [navfiles, navpath] = uigetfile('data/mat_files/nav/*.*','Select NAVMAT files','MultiSelect','on');
                        addpath(genpath(navpath));
                        app.rcfEFL.Value = navfiles;
                        if ischar(navfiles); rcf.EFL = {navfiles}; else; rcf.EFL = navfiles'; end
                        rcf.NEF = length(rcf.EFL);
                        app.rcfNEFmsg.Text = sprintf('%1.0f ephemeris files selected.',rcf.NEF);
                    catch
                    end
                else
                    try [ephfiles, ephpath] = uigetfile('data/mat_files/eph/*.*','Select EPHMAT files','MultiSelect','on');
                        addpath(genpath(ephpath));
                        app.rcfEFL.Value = ephfiles;
                        if ischar(ephfiles); rcf.EFL = {ephfiles}; else; rcf.EFL = ephfiles'; end
                        rcf.NEF = length(rcf.EFL);
                        app.rcfNEFmsg.Text = sprintf('%1.0f ephemeris files selected.',rcf.NEF);
                    catch
                    end
                end
            end
            
        end

        % Button pushed function: rcfOFClear
        function rcfOFClearButtonPushed(app, event)
            % RCF/FILES: Observation files (CLEAR BUTTON)
            global rcf
            rcf.OFL = {};
            app.rcfOFL.Value = '';
            rcf.NOF = length(rcf.OFL);
            app.rcfNOFmsg.Text = sprintf('%1.0f observation files selected.',rcf.NOF);
        end

        % Button pushed function: rcfEFClear
        function rcfEFClearButtonPushed(app, event)
            % RCF/FILES: Ephemeris files (CLEAR BUTTON)
            global rcf
            rcf.EFL = {};
            app.rcfEFL.Value = '';
            rcf.NEF = length(rcf.EFL);
            app.rcfNEFmsg.Text = sprintf('%1.0f ephemeris files selected.',rcf.NEF);
        end

        % Button pushed function: rcfRun
        function rcfRunButtonPushed(app, event)
            % RCF/RUN: READ & CONVERT BUTTON
            global rcf
            % Process type 1
            if rcf.PT == 1
                tic;
                nop = rcf.NOF + rcf.NEF; % number of processes
                c_p = 0;                 % completed processes
                if rcf.NOF~=0
                    app.GIRASStatus.Value = ['>>> [START] Read & convert observation files.';app.GIRASStatus.Value]; drawnow;
                    for i = 1:length(rcf.OFL)
                        c_p = c_p + 1;
                        obsmat = rinexversion(rcf.OFL{i});
                        matfile = fullfile('data/mat_files/obs/',[obsmat.info.markername,'_',num2str(obsmat.info.DoY(1)),num2str(obsmat.info.DoY(2),'%03.f'),'.mat']);
                        save(matfile,'obsmat');
                        app.GIRASStatus.Value = [['[',num2str(100*(c_p/nop),'%.2f'),'%] ',rcf.OFL{i},' is completed (',num2str(i),'/',num2str(length(rcf.OFL)),').'];app.GIRASStatus.Value]; drawnow;
                    end
                    app.GIRASStatus.Value = ['>>> [END] Read & convert observation files.';app.GIRASStatus.Value]; drawnow;
                end
                if rcf.NEF~=0
                    app.GIRASStatus.Value = ['>>> [START] Read & convert ephmeris files.';app.GIRASStatus.Value]; drawnow;
                    if rcf.EFT == 1
                        for i = 1:length(rcf.EFL)
                            c_p = c_p + 1;
                            navmat = gpsNAVreader(rcf.EFL{i});
                            matfile = fullfile('data/mat_files/nav/',['nav_',num2str(navmat.info.DoY(1)),num2str(navmat.info.DoY(2),'%03.f'),'.mat']);
                            save(matfile,'navmat');
                            app.GIRASStatus.Value = [['[',num2str(100*(c_p/nop),'%.2f'),'%] ',rcf.EFL{i},' is completed (',num2str(i),'/',num2str(length(rcf.EFL)),').'];app.GIRASStatus.Value]; drawnow;
                        end
                        app.GIRASStatus.Value = ['>>> [END] Read & convert ephemeris files.';app.GIRASStatus.Value]; drawnow;
                    else
                        for i = 1:length(rcf.EFL)
                            c_p = c_p + 1;
                            ephmat = sp3reader(rcf.EFL{i});
                            matfile = fullfile('data/mat_files/eph/',['eph_',num2str(ephmat.info.DoY(1)),num2str(ephmat.info.DoY(2),'%03.f'),'.mat']);
                            save(matfile,'ephmat');
                            app.GIRASStatus.Value = [['[',num2str(100*(c_p/nop),'%.2f'),'%] ',rcf.EFL{i},' is completed (',num2str(i),'/',num2str(length(rcf.EFL)),').'];app.GIRASStatus.Value]; drawnow;
                        end
                        app.GIRASStatus.Value = ['>>> [END] Read & convert ephemeris files.';app.GIRASStatus.Value]; drawnow;
                    end
                end
                if (rcf.NOF==0) && (rcf.NEF==0)
                    uialert(app.GIRASUI,'There is no file to convert.','Warning');
                end
                [Th, Tm, Ts] = hms(seconds(toc));
                app.GIRASStatus.Value = [['>>> ',num2str(100*(c_p/nop),'%.2f'),'% of process is completed (',num2str(Th,'%.0f'),' h ',num2str(Tm,'%.0f'),' m ',num2str(Ts,'%.0f'),' s).'];app.GIRASStatus.Value]; drawnow; 
            % Process type 2
            elseif rcf.IT == 1
                tic;
                nop = rcf.NOF + rcf.NEF + rcf.NOF; % number of processes
                c_p = 0;                           % completed processes
                if rcf.NOF~=0
                    app.GIRASStatus.Value = ['>>> [START] Read & convert observation files.';app.GIRASStatus.Value]; drawnow;
                    for i = 1:length(rcf.OFL)
                        c_p = c_p + 1;
                        obsmat = rinexversion(rcf.OFL{i});
                        matfile = fullfile('data/mat_files/obs/',[obsmat.info.markername,'_',num2str(obsmat.info.DoY(1)),num2str(obsmat.info.DoY(2),'%03.f'),'.mat']);
                        save(matfile,'obsmat');
                        SMFL(i,1) = string([obsmat.info.markername,'_snr_',num2str(obsmat.info.DoY(1)),num2str(obsmat.info.DoY(2),'%03.f'),'.mat']);
                        OMFL(i,1) = string([obsmat.info.markername,'_',num2str(obsmat.info.DoY(1)),num2str(obsmat.info.DoY(2),'%03.f'),'.mat']);
                        app.GIRASStatus.Value = [['[',num2str(100*(c_p/nop),'%.2f'),'%] ',rcf.OFL{i},' is completed (',num2str(i),'/',num2str(length(rcf.OFL)),').'];app.GIRASStatus.Value]; drawnow;
                    end
                    app.GIRASStatus.Value = ['>>> [END] Read & convert observation files.';app.GIRASStatus.Value]; drawnow;
                end
                if rcf.NEF~=0
                    app.GIRASStatus.Value = ['>>> [START] Read & convert ephmeris files.';app.GIRASStatus.Value]; drawnow;
                    if rcf.EFT == 1
                        for i = 1:length(rcf.EFL)
                            c_p = c_p + 1;
                            navmat = gpsNAVreader(rcf.EFL{i});
                            matfile = fullfile('data/mat_files/nav/',['nav_',num2str(navmat.info.DoY(1)),num2str(navmat.info.DoY(2),'%03.f'),'.mat']);
                            save(matfile,'navmat');
                            EMFL(i,1) = string(['nav_',num2str(navmat.info.DoY(1)),num2str(navmat.info.DoY(2),'%03.f'),'.mat']);
                            app.GIRASStatus.Value = [['[',num2str(100*(c_p/nop),'%.2f'),'%] ',rcf.EFL{i},' is completed (',num2str(i),'/',num2str(length(rcf.EFL)),').'];app.GIRASStatus.Value]; drawnow;
                        end
                        app.GIRASStatus.Value = ['>>> [END] Read & convert ephemeris files.';app.GIRASStatus.Value]; drawnow;
                    else
                        for i = 1:length(rcf.EFL)
                            c_p = c_p + 1;
                            ephmat = sp3reader(rcf.EFL{i});
                            matfile = fullfile('data/mat_files/eph/',['eph_',num2str(ephmat.info.DoY(1)),num2str(ephmat.info.DoY(2),'%03.f'),'.mat']);
                            save(matfile,'ephmat');
                            EMFL(i,1) = string(['eph_',num2str(ephmat.info.DoY(1)),num2str(ephmat.info.DoY(2),'%03.f'),'.mat']);
                            app.GIRASStatus.Value = [['[',num2str(100*(c_p/nop),'%.2f'),'%] ',rcf.EFL{i},' is completed (',num2str(i),'/',num2str(length(rcf.EFL)),').'];app.GIRASStatus.Value]; drawnow;
                        end
                        app.GIRASStatus.Value = ['>>> [END] Read & convert ephemeris files.';app.GIRASStatus.Value]; drawnow;
                    end
                end
                if (rcf.NOF==0) && (rcf.NEF==0)
                    uialert(app.GIRASUI,'There is no file to convert.','Warning');
                end
                % create SNRMAT files
                app.GIRASStatus.Value = ['>>> [START] Create SNRMAT files.';app.GIRASStatus.Value]; drawnow;
                for i = 1:size(OMFL,1)
                    if ~isnan(sum(rcf.XYZ))
                        snrmat = createSNRMATfile(OMFL(i),EMFL(i),rcf.EFT,rcf.XYZ);
                    else
                        snrmat = createSNRMATfile(OMFL(i),EMFL(i),rcf.EFT);
                    end
                    save(['data/mat_files/snr/',char(SMFL(i))],'snrmat');
                    c_p = c_p + 1;
                    app.GIRASStatus.Value = [['[',num2str(100*(c_p/nop),'%.2f'),'%] ',char(SMFL(i)),' is completed (',num2str(i),'/',num2str(size(SMFL,1)),').'];app.GIRASStatus.Value]; drawnow;
                end
                app.GIRASStatus.Value = ['>>> [END] Create SNRMAT files.';app.GIRASStatus.Value]; drawnow;
                [Th, Tm, Ts] = hms(seconds(toc));
                app.GIRASStatus.Value = [['>>> ',num2str(100*(c_p/nop),'%.2f'),'% of process is completed (',num2str(Th,'%.0f'),' h ',num2str(Tm,'%.0f'),' m ',num2str(Ts,'%.0f'),' s).'];app.GIRASStatus.Value]; drawnow;
            else
                tic;
                nop = rcf.NOF; % number of processes
                c_p = 0;       % completed processes
                if rcf.NOF~=0 && rcf.NEF~=0 && rcf.NOF==rcf.NEF
                    app.GIRASStatus.Value = ['>>> [START] Create SNRMAT files.';app.GIRASStatus.Value]; drawnow;
                    for i = 1:length(rcf.OFL)
                        if ~isnan(sum(rcf.XYZ))
                            snrmat = createSNRMATfile(rcf.OFL{i},rcf.EFL{i},rcf.EFT,rcf.XYZ);
                        else
                            snrmat = createSNRMATfile(rcf.OFL{i},rcf.EFL{i},rcf.EFT);
                        end
                        SMFN = [snrmat.info.markername,'_snr_',num2str(snrmat.info.DoY(1)),num2str(snrmat.info.DoY(2),'%03.f'),'.mat'];
                        save(['data/mat_files/snr/',SMFN],'snrmat');
                        c_p = c_p + 1;
                        app.GIRASStatus.Value = [['[',num2str(100*(c_p/nop),'%.2f'),'%] ',SMFN,' is completed (',num2str(i),'/',num2str(length(rcf.OFL)),').'];app.GIRASStatus.Value]; drawnow;
                    end
                end
                app.GIRASStatus.Value = ['>>> [END] Create SNRMAT files.';app.GIRASStatus.Value]; drawnow;
                [Th, Tm, Ts] = hms(seconds(toc));
                app.GIRASStatus.Value = [['>>> ',num2str(100*(c_p/nop),'%.2f'),'% of process is completed (',num2str(Th,'%.0f'),' h ',num2str(Tm,'%.0f'),' m ',num2str(Ts,'%.0f'),' s).'];app.GIRASStatus.Value]; drawnow;
            end
        end

        % Button pushed function: praSMBrowse
        function praSMBrowseButtonPushed(app, event)
            global pra snrmat
            % Clear values
            app.NumberofsatellitetracksEditField.Value = 0;
            app.DistancefromellipsecenterEditField.Value = 0;
            app.AreaofanFFZellipsem2EditField.Value = 0;
            app.TotalFFZaream2EditField.Value = 0;
            cla(app.FFZAxes);
            try [SMfile, SMpath] = uigetfile('data/mat_files/snr/*.*','Select SNRMAT file','MultiSelect','off');
                pra.PD = 1; app.SNRButton.Value = true;
                addpath(genpath(SMpath));
                app.praSMfile.Value = SMfile;
                load(SMfile);
                % Listboxes
                app.praSLBox.Items = snrmat.info.observedsats;
                app.praSLBox.Value = app.praSLBox.Items{1};
                app.praSNRLBox.Items = snrmat.info.typesofsnr;
                app.praSNRLBox.Value = app.praSNRLBox.Items{1};
                app.praSLBox_2.Items = snrmat.info.observedsats;
                app.praSLBox_2.Value = app.praSLBox_2.Items{1};
                % Checkboxes
                app.GPSCheckBox.Value = true; app.GLONASSCheckBox.Value = true; app.GALILEOCheckBox.Value = true; app.BEIDOUCheckBox.Value = true;
                % Plot SNR
                plotSNR(app,pra,snrmat,app.praSLBox.Value,app.praSNRLBox.Value);
                % Plot sky view
                plotskyview(app, snrmat.info.observedsats, snrmat.info.observedsats{1}, snrmat)
                % SNR types dropdown
                app.SNRdataDropDown_2.Items = snrmat.info.typesofsnr;
            catch
            end
        end

        % Value changed function: praSLBox, praSNRLBox
        function praSLBoxValueChanged(app, event)
            global pra snrmat
            if pra.PD == 1
                % Plot SNR
                try plotSNR(app,pra,snrmat,app.praSLBox.Value,app.praSNRLBox.Value); catch; cla(app.SDAxes,'reset'); end
            else
                % Plot dSNR
                if ~strcmp(app.praSMfile.Value,'')
                    cla(app.SDAxes,'reset');
                    dSNRdata = create_dSNRdata(snrmat,app.praSLBox.Value,app.praSNRLBox.Value,pra.angEL,pra.angAZ,pra.PD);
                    if ~isempty(dSNRdata)
                        if size(dSNRdata,2) == 1; pra.ST = 1; app.TracknoSpinner.Value = 1; else; app.TracknoSpinner.Limits = [1 size(dSNRdata,2)]; end
                        if pra.ST > size(dSNRdata,2); pra.ST = 1; end
                        plotdSNR(app,pra,dSNRdata,app.praSLBox.Value,app.praSNRLBox.Value);
                    end
                end
            end
        end

        % Selection changed function: PlotdataButtonGroup
        function PlotdataButtonGroupSelectionChanged(app, event)
            % PRA/SETTINGS: Plot data
            global pra snrmat
            if ~strcmp(app.praSMfile.Value,'')
                if app.PlotdataButtonGroup.Buttons(1).Value
                    pra.PD = 1;
                    % Settings
                    app.SDUdB.Enable = 'on'; app.SDUVV.Enable = 'on';
                    app.TUH.Enable = 'on'; app.TUSec.Enable = 'on';
                    app.TracknoSpinner.Enable = 'off'; app.PolynomialdegreeSpinner.Enable = 'off';
                    % Plot SNR
                    plotSNR(app,pra,snrmat,app.praSLBox.Value,app.praSNRLBox.Value);
                else
                    pra.PD = 2;
                    % Settings
                    app.SDUdB.Enable = 'off'; app.SDUVV.Enable = 'off'; app.SDUVV.Value = 1; pra.SDU = 2;
                    app.TUH.Enable = 'off'; app.TUSec.Enable = 'off';
                    app.TracknoSpinner.Enable = 'on'; app.PolynomialdegreeSpinner.Enable = 'on';
                    % Plot dSNR
                    if ~strcmp(app.praSMfile.Value,'')
                        dSNRdata = create_dSNRdata(snrmat,app.praSLBox.Value,app.praSNRLBox.Value,pra.angEL,pra.angAZ,pra.PD);
                        if ~isempty(dSNRdata)
                            if size(dSNRdata,2) == 1; pra.ST = 1; app.TracknoSpinner.Value = 1; else; app.TracknoSpinner.Limits = [1 size(dSNRdata,2)]; end
                            if pra.ST > size(dSNRdata,2); pra.ST = 1; end
                            plotdSNR(app,pra,dSNRdata,app.praSLBox.Value,app.praSNRLBox.Value);
                        end
                    end
                end
            end
        end

        % Selection changed function: praSDU
        function praSDUSelectionChanged(app, event)
            % PRA/SETTINGS: SNR/dSNR unit
            global pra snrmat
            if ~strcmp(app.praSMfile.Value,'')
                if app.praSDU.Buttons(1).Value
                    pra.SDU = 1;
                else
                    pra.SDU = 2;
                end
                if pra.PD == 1
                    % Plot SNR
                    plotSNR(app,pra,snrmat,app.praSLBox.Value,app.praSNRLBox.Value);
                end
            end
        end

        % Selection changed function: praTU
        function praTUSelectionChanged(app, event)
            % PRA/SETTINGS: Time unit
            global pra snrmat
            if ~strcmp(app.praSMfile.Value,'')
                if app.praTU.Buttons(1).Value
                    pra.TU = 1;
                else
                    pra.TU = 2;
                end
                if pra.PD == 1
                    % Plot SNR
                    plotSNR(app,pra,snrmat,app.praSLBox.Value,app.praSNRLBox.Value);
                end
            end
        end

        % Value changed function: minEL
        function minELValueChanged(app, event)
            % PRA/SETTINGS: Minimum elevation angle
            global pra snrmat
            pra.angEL(1) = app.minEL.Value;
            app.maxEL.Limits = [app.minEL.Value + 0.99 90];
            if ~strcmp(app.praSMfile.Value,'')
                if pra.PD == 1
                    % Plot SNR
                    plotSNR(app,pra,snrmat,app.praSLBox.Value,app.praSNRLBox.Value);
                else
                    % Plot dSNR
                    if ~strcmp(app.praSMfile.Value,'')
                        dSNRdata = create_dSNRdata(snrmat,app.praSLBox.Value,app.praSNRLBox.Value,pra.angEL,pra.angAZ,pra.PD);
                        if ~isempty(dSNRdata)
                            if size(dSNRdata,2) == 1; pra.ST = 1; app.TracknoSpinner.Value = 1; else; app.TracknoSpinner.Limits = [1 size(dSNRdata,2)]; end
                            if pra.ST > size(dSNRdata,2); pra.ST = 1; end
                            plotdSNR(app,pra,dSNRdata,app.praSLBox.Value,app.praSNRLBox.Value);
                        end
                    end
                end
            end
        end

        % Value changed function: maxEL
        function maxELValueChanged(app, event)
            % PRA/SETTINGS: Maximum elevation angle
            global pra snrmat
            pra.angEL(2) = app.maxEL.Value;
            app.minEL.Limits = [0 app.maxEL.Value - 0.99];
            if ~strcmp(app.praSMfile.Value,'')
                if pra.PD == 1
                    % Plot SNR
                    plotSNR(app,pra,snrmat,app.praSLBox.Value,app.praSNRLBox.Value);
                else
                    % Plot dSNR
                    if ~strcmp(app.praSMfile.Value,'')
                        dSNRdata = create_dSNRdata(snrmat,app.praSLBox.Value,app.praSNRLBox.Value,pra.angEL,pra.angAZ,pra.PD);
                        if ~isempty(dSNRdata)
                            if size(dSNRdata,2) == 1; pra.ST = 1; app.TracknoSpinner.Value = 1; else; app.TracknoSpinner.Limits = [1 size(dSNRdata,2)]; end
                            if pra.ST > size(dSNRdata,2); pra.ST = 1; end
                            plotdSNR(app,pra,dSNRdata,app.praSLBox.Value,app.praSNRLBox.Value);
                        end
                    end
                end
            end
        end

        % Value changed function: minAZ
        function minAZValueChanged(app, event)
            % PRA/SETTINGS: Minimum azimuth angle
            global pra snrmat
            pra.angAZ(1) = app.minAZ.Value;
            app.maxAZ.Limits = [app.minAZ.Value + 0.99 360];
            if ~strcmp(app.praSMfile.Value,'')
                if pra.PD == 1
                    % Plot SNR
                    plotSNR(app,pra,snrmat,app.praSLBox.Value,app.praSNRLBox.Value);
                else
                    % Plot dSNR
                    if ~strcmp(app.praSMfile.Value,'')
                        dSNRdata = create_dSNRdata(snrmat,app.praSLBox.Value,app.praSNRLBox.Value,pra.angEL,pra.angAZ,pra.PD);
                        if ~isempty(dSNRdata)
                            if size(dSNRdata,2) == 1; pra.ST = 1; app.TracknoSpinner.Value = 1; else; app.TracknoSpinner.Limits = [1 size(dSNRdata,2)]; end
                            if pra.ST > size(dSNRdata,2); pra.ST = 1; end
                            plotdSNR(app,pra,dSNRdata,app.praSLBox.Value,app.praSNRLBox.Value);
                        end
                    end
                end
            end
        end

        % Value changed function: maxAZ
        function maxAZValueChanged(app, event)
            % PRA/SETTINGS: Minimum azimuth angle
            global pra snrmat
            pra.angAZ(2) = app.maxAZ.Value;
            app.minAZ.Limits = [0 app.maxAZ.Value - 0.99];
            if ~strcmp(app.praSMfile.Value,'')
                if pra.PD == 1
                    % Plot SNR
                    plotSNR(app,pra,snrmat,app.praSLBox.Value,app.praSNRLBox.Value);
                else
                    % Plot dSNR
                    if ~strcmp(app.praSMfile.Value,'')
                        dSNRdata = create_dSNRdata(snrmat,app.praSLBox.Value,app.praSNRLBox.Value,pra.angEL,pra.angAZ,pra.PD);
                        if ~isempty(dSNRdata)
                            if size(dSNRdata,2) == 1; pra.ST = 1; app.TracknoSpinner.Value = 1; else; app.TracknoSpinner.Limits = [1 size(dSNRdata,2)]; end
                            if pra.ST > size(dSNRdata,2); pra.ST = 1; end
                            plotdSNR(app,pra,dSNRdata,app.praSLBox.Value,app.praSNRLBox.Value);
                        end
                    end
                end
            end
        end

        % Button pushed function: SDAxesExpPlot
        function SDAxesExpPlotButtonPushed(app, event)
            global pra snrmat
            if ~strcmp(app.praSMfile.Value,'')
                if pra.PD == 1
                    % Export SNR plot
                    try exportSNRplot(app,pra,snrmat,app.praSLBox.Value,app.praSNRLBox.Value); catch; end
                else
                    % Export dSNR plot
                    if ~strcmp(app.praSMfile.Value,'')
                        dSNRdata = create_dSNRdata(snrmat,app.praSLBox.Value,app.praSNRLBox.Value,pra.angEL,pra.angAZ,pra.PD);
                        if ~isempty(dSNRdata)
                            if size(dSNRdata,2) == 1; pra.ST = 1; app.TracknoSpinner.Value = 1; else; app.TracknoSpinner.Limits = [1 size(dSNRdata,2)]; end
                            if pra.ST > size(dSNRdata,2); pra.ST = 1; end
                            exportdSNRplot(app,pra,dSNRdata,app.praSLBox.Value,app.praSNRLBox.Value);
                        end
                    end
                end
            end
        end

        % Value changed function: PolynomialdegreeSpinner, 
        % TracknoSpinner
        function TracknoSpinnerValueChanged(app, event)
            global pra snrmat
            pra.ST = app.TracknoSpinner.Value;
            pra.PD = app.PolynomialdegreeSpinner.Value;
            % Plot dSNR
            if ~strcmp(app.praSMfile.Value,'')
                dSNRdata = create_dSNRdata(snrmat,app.praSLBox.Value,app.praSNRLBox.Value,pra.angEL,pra.angAZ,pra.PD);
                if ~isempty(dSNRdata)
                    if size(dSNRdata,2) == 1; pra.ST = 1; app.TracknoSpinner.Value = 1; else; app.TracknoSpinner.Limits = [1 size(dSNRdata,2)]; end
                    if pra.ST > size(dSNRdata,2); pra.ST = 1; end
                    plotdSNR(app,pra,dSNRdata,app.praSLBox.Value,app.praSNRLBox.Value);
                end
            end
        end

        % Button pushed function: anaSFBrowse
        function anaSFBrowseButtonPushed(app, event)
            % ANA/FILES: SNRMAT files (BROWSE BUTTON)
            global ana
            try [snrmatfiles, snrpath] = uigetfile('data/mat_files/snr/*.*','Select SNRMAT files','MultiSelect','on');
                addpath(genpath(snrpath));
                app.anaSFL.Value = snrmatfiles;
                if ischar(snrmatfiles); ana.SFL = {snrmatfiles}; else; ana.SFL = snrmatfiles'; end
                ana.NSF = length(ana.SFL);
                app.anaNSFmsg.Text = sprintf('%1.0f SNRMAT files selected.',ana.NSF);
                if ana.NSF~=0
                app.GIRASStatus_2.Value = ['>>> Loading files...';app.GIRASStatus_2.Value]; drawnow;
                % SNR types dropdown
                SNRtypesDD = {}; cDD = 0;
                app.GIRASStatus_2.Value = ['>>> Looking for SNR types...';app.GIRASStatus_2.Value]; drawnow;
                app.GIRASStatus_2.Value = ['>>> Please wait...';app.GIRASStatus_2.Value]; drawnow;
                app.SNRdataDropDown.Enable = 'Off'; app.rcfRun_2.Enable = 'Off';
                for i = 1:length(ana.SFL)
                    load(ana.SFL{i});
                    for j = 1:length(snrmat.info.typesofsnr); cDD = cDD + 1; SNRtypesDD{cDD,1} = snrmat.info.typesofsnr{j}; end
                end
                SNRtypesDD = unique(SNRtypesDD); app.SNRdataDropDown.Items = [{'All'}; SNRtypesDD];
                app.GIRASStatus_2.Value = ['>>> Ready for processing...';app.GIRASStatus_2.Value]; drawnow;
                app.SNRdataDropDown.Enable = 'On'; app.rcfRun_2.Enable = 'On';
                end
            catch
            end
        end

        % Button pushed function: anaSFClear
        function anaSFClearButtonPushed(app, event)
            % ANA/FILES: SNRMAT files (CLEAR BUTTON)
            global ana
            ana.SFL = {};
            app.anaSFL.Value = '';
            ana.NSF = length(ana.SFL);
            app.anaNSFmsg.Text = sprintf('%1.0f observation files selected.',ana.NSF);
            app.SNRdataDropDown.Items = {'All'}; ana.SD = 'All';
            app.FilenameEditField.Value = '';
        end

        % Value changed function: BEIDOUCheckBox_2, 
        % DesiredprecisionmEditField, GALILEOCheckBox_2, 
        % GLONASSCheckBox_2, GPSCheckBox_2, 
        % MaximumreflectorheightmEditField, 
        % PolynomialdegreeSpinner_2, SNRdataDropDown
        function GPSCheckBox_2ValueChanged(app, event)
            % ANA/SETTINGS
            global ana
            if app.GPSCheckBox_2.Value; ana.sats(1) = 1; else; ana.sats(1) = 0; end
            if app.GLONASSCheckBox_2.Value; ana.sats(2) = 1; else; ana.sats(2) = 0; end
            if app.GALILEOCheckBox_2.Value; ana.sats(3) = 1; else; ana.sats(3) = 0; end
            if app.BEIDOUCheckBox_2.Value; ana.sats(4) = 1; else; ana.sats(4) = 0; end
            ana.SD = app.SNRdataDropDown.Value; ana.PD = app.PolynomialdegreeSpinner_2.Value;
            ana.hmax = app.MaximumreflectorheightmEditField.Value;
            ana.prec = app.DesiredprecisionmEditField.Value;
        end

        % Value changed function: ElevationEditField
        function ElevationEditFieldValueChanged(app, event)
            % ANA/SETTINGS
            global ana
            anglimit = app.ElevationEditField.Value;
            try eval(['ana.angEL = ',anglimit,';']); catch; end
        end

        % Value changed function: AzimuthEditField
        function AzimuthEditFieldValueChanged(app, event)
            % ANA/SETTINGS
            global ana
            anglimit = app.AzimuthEditField.Value;
            try eval(['ana.angAZ = ',anglimit,';']); catch; end
        end

        % Button pushed function: rcfRun_2
        function rcfRun_2ButtonPushed(app, event)
            % ANA/RUN
            global ana
            
            if ana.NSF~=0
                if strcmp(app.FilenameEditField.Value,'')
                    tnow = datetime('now');
                    resfilename = ['untitledest','_',num2str(year(tnow),'%04.f'),num2str(month(tnow),'%02.f'),num2str(day(tnow),'%02.f'),'_',num2str(hour(tnow),'%02.f'),num2str(minute(tnow),'%02.f')];
                    matfile = fullfile('results/all/mat/',[resfilename,'.mat']);
                    txtfile = fullfile('results/all/txt/',[resfilename,'.txt']);
                else
                    matfile = fullfile('results/all/mat/',[app.FilenameEditField.Value,'.mat']);
                    txtfile = fullfile('results/all/txt/',[app.FilenameEditField.Value,'.txt']);
                end
                fileID = fopen(txtfile,'w');
                tic
                resultsfile = {}; rownum = 0; 
                app.GIRASStatus.Value = ['>>> [START] ANALYSIS: MAKE ESTIMATIONS';app.GIRASStatus.Value]; drawnow;
                app.GIRASStatus_2.Value = ['>>> [START] ANALYSIS';app.GIRASStatus_2.Value]; drawnow;
                for i1 = 1:length(ana.SFL)
                    app.GIRASStatus.Value = [['Analyzing ',ana.SFL{i1},'... (',num2str(i1),'/',num2str(length(ana.SFL)),')'];app.GIRASStatus.Value]; drawnow;
                    app.GIRASStatus_2.Value = [['>>> FILE: ',ana.SFL{i1}];app.GIRASStatus_2.Value]; drawnow;
                    load(ana.SFL{i1});
                    for i2 = 1:length(snrmat.info.observedsats)
                        satprn = snrmat.info.observedsats{i2};
                        if strcmp(satprn(1),'G') && ana.sats(1) == 1; runkey = 1;
                        elseif strcmp(satprn(1),'R') && ana.sats(2) == 1; runkey = 1;
                        elseif strcmp(satprn(1),'E') && ana.sats(3) == 1; runkey = 1;
                        elseif strcmp(satprn(1),'C') && ana.sats(4) == 1; runkey = 1;
                        else; runkey = 0;
                        end
                        if runkey == 1
                            app.GIRASStatus_2.Value = ['Analyzing data of ',snrmat.info.observedsats{i2},' satellite...']; drawnow;
                            for i3 = 1:length(snrmat.info.typesofsnr)
                                key = 0;
                                if strcmp(ana.SD,'All')
                                    key = 1;
                                    dSNRdata = create_dSNRdata(snrmat,snrmat.info.observedsats{i2},snrmat.info.typesofsnr{i3},ana.angEL,ana.angAZ,ana.PD);
                                elseif strcmp(ana.SD,snrmat.info.typesofsnr{i3})
                                    key = 1;
                                    dSNRdata = create_dSNRdata(snrmat,snrmat.info.observedsats{i2},snrmat.info.typesofsnr{i3},ana.angEL,ana.angAZ,ana.PD);
                                end
                                if key == 1 && ~isempty(dSNRdata)
                                    wL = find_WL(find_sat_index(snrmat.info.observedsats{i2}),snrmat.info.typesofsnr{i3});
                                    for i4 = 1:size(dSNRdata,2)
                                        if size(dSNRdata{1,i4},1) > 1
                                            rownum = rownum + 1;
                                            [P,f,pha] = lombGIRAS(dSNRdata{1,i4}(:,6),dSNRdata{1,i4}(:,2),wL,ana.hmax,ana.prec);
                                            if length(f(P==max(P))) == 1; f_dom = f(P==max(P)); else; f_dom = NaN; end            % Dominant frequency
                                            if length(max(P)) == 1; amp_dom = max(P); else; amp_dom = NaN; end                    % Dominant amplitude
                                            if length(pha(P==max(P))) == 1; pha_dom = pha(P==max(P)); else; pha_dom = NaN; end    % Dominant phase
                                            fprintf(fileID,'%4.0f %03.f %5s %5s %2.0f %5s %10.4f %10.4f %10.4f %10.2f %10.2f %10.0f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f\n',...
                                                snrmat.info.DoY(1),snrmat.info.DoY(2),snrmat.info.observedsats{i2},snrmat.info.typesofsnr{i3},i4,dSNRdata{2,i4},...
                                                f_dom,amp_dom,pha_dom,min(dSNRdata{1,i4}(:,1)),max(dSNRdata{1,i4}(:,1)),size(dSNRdata{1,i4},1),min(dSNRdata{1,i4}(:,2)),max(dSNRdata{1,i4}(:,2)),...
                                                min(dSNRdata{1,i4}(:,3)),max(dSNRdata{1,i4}(:,3)),max(P),sum(P(~isnan(P)))/(length(P(~isnan(P))-1)));
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
                fclose(fileID);
                resultsfile = writeresultstomatfile(app,txtfile);
                if ~isempty(resultsfile)
                    if strcmp(app.FilenameEditField.Value,'')
                        save(matfile,'resultsfile');
                        msgbox(['File saved as ',resfilename,'.']);
                    else
                        save(matfile,'resultsfile');
                        msgbox(['File saved as ',app.FilenameEditField.Value,'.']);
                    end
                end
                app.GIRASStatus_2.Value = ['>>> [END] ANALYSIS']; drawnow;
                app.GIRASStatus.Value = ['>>> [END] ANALYSIS: MAKE ESTIMATIONS';app.GIRASStatus.Value]; drawnow;
                [Th, Tm, Ts] = hms(seconds(toc));
                app.GIRASStatus.Value = [['>>> Analyzing process is completed (',num2str(Th,'%.0f'),' h ',num2str(Tm,'%.0f'),' m ',num2str(Ts,'%.0f'),' s).'];app.GIRASStatus.Value]; drawnow;
            end
        end

        % Button pushed function: praSMBrowse_2
        function praSMBrowse_2ButtonPushed(app, event)
            global resultsfile
            resultsfile = {};
            try [EMfile, EMpath] = uigetfile('results/all/mat/*.*','Select estimation file','MultiSelect','off');
                addpath(genpath(EMpath));
                app.praSMfile_2.Value = EMfile;
                load(EMfile);
                [~,name,~] = fileparts(EMfile); if exist(name,'var'); eval(['resultsfile = ',name,';']); end
                app.MinMaxfrequencyEditField.Value = ['[',num2str(floor(min(cell2mat(resultsfile(:,7))))),' ',num2str(ceil(max(cell2mat(resultsfile(:,7))))),']'];
                set(app.UITable,'data',{});
            catch
            end
        end

        % Value changed function: BNCcb, BNCco, DropDown, MADcb, 
        % MADco, MERcb, MERco, MinMaxfrequencyEditField
        function MADcbValueChanged(app, event)
            global ana2
            if app.MADcb.Value; ana2.MAD(2) = 1; else; ana2.MAD(2) = 0; end
            if app.BNCcb.Value; ana2.BNC(2) = 1; else; ana2.BNC(2) = 0; end
            if app.MERcb.Value; ana2.MER(2) = 1; else; ana2.MER(2) = 0; end
            ana2.MAD(1) = app.MADco.Value; ana2.BNC(1) = app.BNCco.Value; ana2.MER(1) = app.MERco.Value;
            ana2.DT = app.DropDown.Value;
        end

        % Button pushed function: SeeresultsButton
        function SeeresultsButtonPushed(app, event)
            global ana2 resultsfile goodresults
            goodresults = {};
            if ~strcmp(app.praSMfile_2.Value,'')
                if ~isempty(resultsfile)
                    % For daily data
                    if ana2.ap2 == 1
                        years = unique(cell2mat(resultsfile(:,1)));
                        days = unique(cell2mat(resultsfile(:,2)));
                        for i = 1:length(years)
                            for j = 1:length(days)
                                resfilt = resultsfile(cell2mat(resultsfile(:,1))==years(i) & cell2mat(resultsfile(:,2))==days(j),:);
                                % Minimum-Maximum frequency
                                eval(['minmaxf = ',app.MinMaxfrequencyEditField.Value,';']); resf = cell2mat(resfilt(:,7));
                                resfilt = resfilt(resf>minmaxf(1) & resf<minmaxf(2),:);
                                % Minimum elevation angle range
                                if ana2.MER(2) == 1; EL = cell2mat(resfilt(:,13:14)); resfilt = resfilt(EL(:,2)-EL(:,1)>ana2.MER(1),:); end
                                % Background noise condition (BNC)
                                if ~isempty(resfilt) && ana2.BNC(2) == 1; BN = cell2mat(resfilt(:,17:18)); resfilt = resfilt(BN(:,1)./BN(:,2)>ana2.BNC(1),:); end
                                % Median absolute deviation (MAD)
                                if ~isempty(resfilt) && ana2.MAD(2) == 1
                                    if strcmp(ana2.DT,'f'); DT = 1; elseif strcmp(ana2.DT,'Amp'); DT = 2; else; DT = 3; end
                                    maddata = cell2mat(resfilt(:,6+DT));
                                    resfilt = resfilt(~isnan(maddata),:);
                                    if size(resfilt,1) > 1
                                        [mad,v,~] = madSNR(maddata(~isnan(maddata)),ana2.MAD(1));
                                        resfilt = resfilt(abs(v) < ana2.MAD(1)*mad,:);
                                    end
                                end
                                goodresults = [goodresults; resfilt];
                            end
                        end
                    % For all data
                    else
                        resfilt = resultsfile;
                        % Minimum-Maximum frequency
                        eval(['minmaxf = ',app.MinMaxfrequencyEditField.Value,';']); resf = cell2mat(resfilt(:,7));
                        resfilt = resfilt(resf>minmaxf(1) & resf<minmaxf(2),:);
                        % Minimum elevation angle range
                        if ana2.MER(2) == 1; EL = cell2mat(resfilt(:,13:14)); resfilt = resfilt(EL(:,2)-EL(:,1)>ana2.MER(1),:); end
                        % Background noise condition (BNC)
                        if ~isempty(resfilt) &&  ana2.BNC(2) == 1; BN = cell2mat(resfilt(:,17:18)); resfilt = resfilt(BN(:,1)./BN(:,2)>ana2.BNC(1),:); end
                        % Median absolute deviation (MAD)
                        if ~isempty(resfilt) &&  ana2.MAD(2) == 1
                            if strcmp(ana2.DT,'f'); DT = 1; elseif strcmp(ana2.DT,'Amp'); DT = 2; else; DT = 3; end
                            maddata = cell2mat(resfilt(:,6+DT));
                            resfilt = resfilt(~isnan(maddata),:);
                            if size(resfilt,1) > 1
                                [mad,v,~] = madSNR(maddata(~isnan(maddata)),ana2.MAD(1));
                                resfilt = resfilt(abs(v) < ana2.MAD(1)*mad,:);
                            end
                        end
                        goodresults = [goodresults; resfilt];
                    end
                end
            end
            if ~isempty(goodresults)
                for i = 1:size(goodresults,1); rowno{i,1} = i; end
                set(app.UITable,'data',[rowno goodresults],'ColumnName',{'#','Year','DoY','Sat','Data','ST no','ST type','Frequency','Amplitude','Phase','Min epoch',...
                    'Max epoch','Num. of epochs','Min elev.','Max elev.','Min azim.','Max azim.','Max P','BN'});
            else
                msgbox('There are no results.');
                set(app.UITable,'data',{});
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global goodresults
            if ~isempty(goodresults)
                if strcmp(app.FilenameEditField_2.Value,'')
                    tnow = datetime('now');
                    resfilename = ['untitledgoodest','_',num2str(year(tnow),'%04.f'),num2str(month(tnow),'%02.f'),num2str(day(tnow),'%02.f'),'_',num2str(hour(tnow),'%02.f'),num2str(minute(tnow),'%02.f')];
                    matfile = fullfile('results/best/mat/',[resfilename,'.mat']);
                    txtfile = fullfile('results/best/txt/',[resfilename,'.txt']);
                else
                    matfile = fullfile('results/best/mat/',[app.FilenameEditField_2.Value,'.mat']);
                    txtfile = fullfile('results/best/txt/',[app.FilenameEditField_2.Value,'.txt']);
                end
                fileID = fopen(txtfile,'w');
                for i = 1:size(goodresults,1)
                    fprintf(fileID,'%4.0f %03.f %5s %5s %2.0f %5s %10.4f %10.4f %10.4f %10.2f %10.2f %10.0f %10.4f %10.4f %10.4f %10.4f %10.4f %10.4f\n',...
                        goodresults{i,1},goodresults{i,2},goodresults{i,3},goodresults{i,4},goodresults{i,5},goodresults{i,6},goodresults{i,7},goodresults{i,8},...
                        goodresults{i,9},goodresults{i,10},goodresults{i,11},goodresults{i,12},goodresults{i,13},goodresults{i,14},goodresults{i,15},...
                        goodresults{i,16},goodresults{i,17},goodresults{i,18});
                end
                fclose(fileID);
                if strcmp(app.FilenameEditField_2.Value,'')
                    save(matfile,'goodresults');
                    msgbox(['File saved as ',resfilename,'.']);
                else
                    save(matfile,'goodresults');
                    msgbox(['File saved as ',app.FilenameEditField_2.Value,'.']);
                end
            end
        end

        % Button pushed function: PlotresultsButton
        function PlotresultsButtonPushed(app, event)
            global goodresults
            if ~isempty(goodresults)
                setXYdata(app,app.XDataDropDown.Value,app.YDataDropDown.Value);
                [Xlabel,Xdata,Ylabel,Ydata] = getXYdata(app,app.XDataDropDown.Value,app.YDataDropDown.Value,goodresults);
                % Plot
                figure('Name','Plot results','Color','White');
                scatter(gca,Xdata,Ydata,'o','MarkerEdgeColor','k','MarkerFaceColor','k');
                xlabel(gca,Xlabel); ylabel(gca,Ylabel);
            else
                msgbox('There are no results to plot.');
            end
            grid(gca,'off'); box(gca,'on');
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            global ana2
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Daily data')
                ana2.ap2 = 1;
            else
                ana2.ap2 = 2;
            end
        end

        % Value changed function: BEIDOUCheckBox, GALILEOCheckBox, 
        % GLONASSCheckBox, GPSCheckBox
        function GPSCheckBoxValueChanged(app, event)
        global snrmat    
        satellites = {}; rowno = 0;
        for i = 1:length(snrmat.info.observedsats)
            if app.GPSCheckBox.Value
                if strcmp(snrmat.info.observedsats{i}(1),'G')
                    rowno = rowno + 1;
                    satellites{rowno,1} = snrmat.info.observedsats{i};
                end
            end
            if app.GLONASSCheckBox.Value
                if strcmp(snrmat.info.observedsats{i}(1),'R')
                    rowno = rowno + 1;
                    satellites{rowno,1} = snrmat.info.observedsats{i};
                end
            end
            if app.GALILEOCheckBox.Value
                if strcmp(snrmat.info.observedsats{i}(1),'E')
                    rowno = rowno + 1;
                    satellites{rowno,1} = snrmat.info.observedsats{i};
                end
            end
            if app.BEIDOUCheckBox.Value
                if strcmp(snrmat.info.observedsats{i}(1),'C')
                    rowno = rowno + 1;
                    satellites{rowno,1} = snrmat.info.observedsats{i};
                end
            end
        end
        app.praSLBox_2.Items = satellites;
        if ~isempty(satellites); app.praSLBox_2.Value = app.praSLBox_2.Items{1}; end
        try plotskyview(app, app.praSLBox_2.Items, app.praSLBox_2.Value, snrmat); catch; end
        end

        % Value changed function: praSLBox_2
        function praSLBox_2ValueChanged(app, event)
            global snrmat
            try plotskyview(app, app.praSLBox_2.Items, app.praSLBox_2.Value, snrmat); catch; end
        end

        % Button pushed function: SDAxesExpPlot_2
        function SDAxesExpPlot_2ButtonPushed(app, event)
            global snrmat
            try exportskyviewplot(app, app.praSLBox_2.Items, app.praSLBox_2.Value, snrmat); catch; end
        end

        % Button pushed function: SDAxesExpPlot_3
        function SDAxesExpPlot_3ButtonPushed(app, event)
            global snrmat
            app.GIRASStatus.Value = ['>>> [START] Export plot FFZ';app.GIRASStatus.Value]; drawnow;
            app.GIRASStatus.Value = ['>>> Please wait...';app.GIRASStatus.Value]; drawnow;
            [n0e0u0, nPePuP, ~, ~, ~, ~, TN] = FFZpoints(snrmat, app.SNRdataDropDown_2.Value, app.ReflectorheightEditField.Value, app.ElevationangleEditField.Value);
            exportFFZplot(app, snrmat, n0e0u0, nPePuP, TN);
            app.GIRASStatus.Value = ['>>> [END] Export plot FFZ';app.GIRASStatus.Value]; drawnow;
        end

        % Button pushed function: SDAxesExpPlot_4
        function SDAxesExpPlot_4ButtonPushed(app, event)
            global snrmat
            app.GIRASStatus.Value = ['>>> [START] Export KML FFZ';app.GIRASStatus.Value]; drawnow;
            app.GIRASStatus.Value = ['>>> Please wait...';app.GIRASStatus.Value]; drawnow;
            filename = [snrmat.info.markername,'_',app.SNRdataDropDown_2.Value(1:2),'_',num2str(snrmat.info.DoY(1)),num2str(snrmat.info.DoY(2),'%03.f'),'_FFZ'];
            [~, ~, ~, ~, el_LLH, el_LLH_kmlcode, ~] = FFZpoints(snrmat, app.SNRdataDropDown_2.Value, app.ReflectorheightEditField.Value, app.ElevationangleEditField.Value);
            exportFFZKML(app, snrmat, el_LLH, el_LLH_kmlcode, filename);
            msgbox(['File saved as ',filename,'.kml','.']);
            app.GIRASStatus.Value = ['>>> [END] Export KML FFZ';app.GIRASStatus.Value]; drawnow;
        end

        % Button pushed function: SDAxesExpPlot_5
        function SDAxesExpPlot_5ButtonPushed(app, event)
            global snrmat
            app.GIRASStatus.Value = ['>>> [START] Plot FFZ';app.GIRASStatus.Value]; drawnow;
            app.GIRASStatus.Value = ['>>> Please wait...';app.GIRASStatus.Value]; drawnow;
            % SNR types dropdown
            app.SNRdataDropDown_2.Items = snrmat.info.typesofsnr;
            % Plot FFZ
            [n0e0u0, nPePuP, ~, ~, ~, ~, TN] = FFZpoints(snrmat, app.SNRdataDropDown_2.Value, app.ReflectorheightEditField.Value, app.ElevationangleEditField.Value);
            plotFFZ(app, snrmat, n0e0u0, nPePuP, TN);
            % Details
            rh = app.ReflectorheightEditField.Value; elev = app.ElevationangleEditField.Value; WL = find_WL(401,app.SNRdataDropDown_2.Value(1:2));
            app.DistancefromellipsecenterEditField.Value = rh/tand(elev);
            app.AreaofanFFZellipsem2EditField.Value = pi*WL*rh*sind(elev)/(sind(elev)^3);
            app.TotalFFZaream2EditField.Value = app.NumberofsatellitetracksEditField.Value*app.AreaofanFFZellipsem2EditField.Value;
            app.GIRASStatus.Value = ['>>> [END] Plot FFZ';app.GIRASStatus.Value]; drawnow;
        end

        % Value changed function: XDataDropDown, YDataDropDown
        function XDataDropDownValueChanged(app, event)
            setXYdata(app,app.XDataDropDown.Value,app.YDataDropDown.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GIRASUI = uifigure;
            app.GIRASUI.Name = 'GIRAS';
            app.GIRASMenu = uitabgroup(app.GIRASUI);
            app.rcf = uitab(app.GIRASMenu);
            app.rcf.Title = 'Read & convert files';
            app.rcfSettings = uipanel(app.rcf);
            app.rcfSettings.Title = 'Settings';
            app.rcfPT = uibuttongroup(app.rcfSettings);
            app.rcfPT.SelectionChangedFcn = createCallbackFcn(app, @rcfPTSelectionChanged, true);
            app.rcfPT.Title = 'Process type';
            app.rcfPT1 = uiradiobutton(app.rcfPT);
            app.rcfPT1.Text = 'Just read & convert files';
            app.rcfPT1.Value = true;
            app.rcfPT2 = uiradiobutton(app.rcfPT);
            app.rcfPT2.Text = 'Read & convert files and create SNRMAT files';
            app.rcfIT = uibuttongroup(app.rcfSettings);
            app.rcfIT.SelectionChangedFcn = createCallbackFcn(app, @rcfITSelectionChanged, true);
            app.rcfIT.Title = 'Input type';
            app.rcfIT1 = uiradiobutton(app.rcfIT);
            app.rcfIT1.Text = 'Raw file';
            app.rcfIT1.Value = true;
            app.rcfIT2 = uiradiobutton(app.rcfIT);
            app.rcfIT2.Text = 'MAT file';
            app.rcfET = uibuttongroup(app.rcfSettings);
            app.rcfET.SelectionChangedFcn = createCallbackFcn(app, @rcfETSelectionChanged, true);
            app.rcfET.Title = 'Ephemeris file';
            app.rcfET1 = uiradiobutton(app.rcfET);
            app.rcfET1.Text = 'Navigation message';
            app.rcfET1.Value = true;
            app.rcfET2 = uiradiobutton(app.rcfET);
            app.rcfET2.Text = 'Precise (.sp3)';
            app.rcfSP = uipanel(app.rcfSettings);
            app.rcfSP.Title = 'Station position';
            app.rcfSPset = uibuttongroup(app.rcfSP);
            app.rcfSPset.SelectionChangedFcn = createCallbackFcn(app, @rcfSPsetSelectionChanged, true);
            app.rcfSP1 = uiradiobutton(app.rcfSPset);
            app.rcfSP1.Enable = 'off';
            app.rcfSP1.Text = 'Use RINEX data';
            app.rcfSP1.Value = true;
            app.rcfSP2 = uiradiobutton(app.rcfSPset);
            app.rcfSP2.Enable = 'off';
            app.rcfSP2.Text = 'Manual';
            app.XmEditFieldLabel = uilabel(app.rcfSP);
            app.XmEditFieldLabel.Text = 'X (m): ';
            app.rcfSPX = uieditfield(app.rcfSP, 'numeric');
            app.rcfSPX.ValueDisplayFormat = '%11.3f';
            app.rcfSPX.ValueChangedFcn = createCallbackFcn(app, @rcfSPXValueChanged, true);
            app.rcfSPX.Enable = 'off';
            app.YmEditFieldLabel = uilabel(app.rcfSP);
            app.YmEditFieldLabel.Text = 'Y (m):';
            app.rcfSPY = uieditfield(app.rcfSP, 'numeric');
            app.rcfSPY.ValueDisplayFormat = '%11.3f';
            app.rcfSPY.ValueChangedFcn = createCallbackFcn(app, @rcfSPYValueChanged, true);
            app.rcfSPY.Enable = 'off';
            app.ZmEditFieldLabel = uilabel(app.rcfSP);
            app.ZmEditFieldLabel.Text = 'Z (m):';
            app.rcfSPZ = uieditfield(app.rcfSP, 'numeric');
            app.rcfSPZ.ValueDisplayFormat = '%11.3f';
            app.rcfSPZ.ValueChangedFcn = createCallbackFcn(app, @rcfSPZValueChanged, true);
            app.rcfSPZ.Enable = 'off';
            app.rcfFiles = uipanel(app.rcf);
            app.rcfFiles.Title = 'Files';
            app.ObservationfilesTextAreaLabel = uilabel(app.rcfFiles);
            app.ObservationfilesTextAreaLabel.Text = 'Observation files';
            app.rcfOFL = uitextarea(app.rcfFiles);
            app.rcfOFL.Editable = 'off';
            app.rcfOFBrowse = uibutton(app.rcfFiles, 'push');
            app.rcfOFBrowse.ButtonPushedFcn = createCallbackFcn(app, @rcfOFBrowseButtonPushed, true);
            app.rcfOFBrowse.Text = 'Browse';
            app.rcfEFBrowse = uibutton(app.rcfFiles, 'push');
            app.rcfEFBrowse.ButtonPushedFcn = createCallbackFcn(app, @rcfEFBrowseButtonPushed, true);
            app.rcfEFBrowse.Text = 'Browse';
            app.rcfOFClear = uibutton(app.rcfFiles, 'push');
            app.rcfOFClear.ButtonPushedFcn = createCallbackFcn(app, @rcfOFClearButtonPushed, true);
            app.rcfOFClear.Text = 'Clear';
            app.rcfEFClear = uibutton(app.rcfFiles, 'push');
            app.rcfEFClear.ButtonPushedFcn = createCallbackFcn(app, @rcfEFClearButtonPushed, true);
            app.rcfEFClear.Text = 'Clear';
            app.rcfNOFmsg = uilabel(app.rcfFiles);
            app.rcfNOFmsg.Text = '0 observation files selected.';
            app.rcfNEFmsg = uilabel(app.rcfFiles);
            app.rcfNEFmsg.Text = '0 ephemeris files selected.';
            app.EphemerisfilesTextAreaLabel = uilabel(app.rcfFiles);
            app.EphemerisfilesTextAreaLabel.Text = 'Ephemeris files';
            app.rcfEFL = uitextarea(app.rcfFiles);
            app.rcfEFL.Editable = 'off';
            app.rcfRun = uibutton(app.rcf, 'push');
            app.rcfRun.ButtonPushedFcn = createCallbackFcn(app, @rcfRunButtonPushed, true);
            app.rcfRun.Text = 'READ & CONVERT';
            app.pra = uitab(app.GIRASMenu);
            app.pra.Title = 'Pre-analysis';
            app.pra_tabs = uitabgroup(app.pra);
            app.praSDDtab = uitab(app.pra_tabs);
            app.praSDDtab.Title = 'SNR & dSNR data';
            app.SatellitesLabel = uilabel(app.praSDDtab);
            app.SatellitesLabel.Text = 'Satellites';
            app.praSLBox = uilistbox(app.praSDDtab);
            app.praSLBox.Items = {};
            app.praSLBox.ValueChangedFcn = createCallbackFcn(app, @praSLBoxValueChanged, true);
            app.praSLBox.Value = {};
            app.SNRtypesListBoxLabel = uilabel(app.praSDDtab);
            app.SNRtypesListBoxLabel.Text = 'SNR types';
            app.praSNRLBox = uilistbox(app.praSDDtab);
            app.praSNRLBox.Items = {};
            app.praSNRLBox.ValueChangedFcn = createCallbackFcn(app, @praSLBoxValueChanged, true);
            app.praSNRLBox.Value = {};
            app.PlotdataButtonGroup = uibuttongroup(app.praSDDtab);
            app.PlotdataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotdataButtonGroupSelectionChanged, true);
            app.PlotdataButtonGroup.Title = 'Plot data';
            app.SNRButton = uiradiobutton(app.PlotdataButtonGroup);
            app.SNRButton.Text = 'SNR';
            app.SNRButton.Value = true;
            app.dSNRButton = uiradiobutton(app.PlotdataButtonGroup);
            app.dSNRButton.Text = 'dSNR';
            app.praSDU = uibuttongroup(app.praSDDtab);
            app.praSDU.SelectionChangedFcn = createCallbackFcn(app, @praSDUSelectionChanged, true);
            app.praSDU.Title = 'SNR/dSNR unit';
            app.SDUdB = uiradiobutton(app.praSDU);
            app.SDUdB.Text = 'dB';
            app.SDUdB.Value = true;
            app.SDUVV = uiradiobutton(app.praSDU);
            app.SDUVV.Text = 'Volts/Volts';
            app.praTU = uibuttongroup(app.praSDDtab);
            app.praTU.SelectionChangedFcn = createCallbackFcn(app, @praTUSelectionChanged, true);
            app.praTU.Title = 'Time unit';
            app.TUH = uiradiobutton(app.praTU);
            app.TUH.Text = 'Hours';
            app.TUH.Value = true;
            app.TUSec = uiradiobutton(app.praTU);
            app.TUSec.Text = 'Seconds';
            app.AnglelimitationPanel = uipanel(app.praSDDtab);
            app.AnglelimitationPanel.Title = 'Angle limitation';
            app.minEL = uieditfield(app.AnglelimitationPanel, 'numeric');
            app.minEL.Limits = [0 89];
            app.minEL.ValueChangedFcn = createCallbackFcn(app, @minELValueChanged, true);
            app.maxEL = uieditfield(app.AnglelimitationPanel, 'numeric');
            app.maxEL.Limits = [1 90];
            app.maxEL.ValueChangedFcn = createCallbackFcn(app, @maxELValueChanged, true);
            app.maxEL.Value = 90;
            app.ElevationangleLabel = uilabel(app.AnglelimitationPanel);
            app.ElevationangleLabel.Text = 'Elevation angle :';
            app.Label = uilabel(app.AnglelimitationPanel);
            app.Label.Text = ' - ';
            app.maxAZ = uieditfield(app.AnglelimitationPanel, 'numeric');
            app.maxAZ.Limits = [0 360];
            app.maxAZ.ValueChangedFcn = createCallbackFcn(app, @maxAZValueChanged, true);
            app.maxAZ.Value = 360;
            app.AzimuthangleLabel = uilabel(app.AnglelimitationPanel);
            app.AzimuthangleLabel.Text = 'Azimuth angle   :';
            app.Label_2 = uilabel(app.AnglelimitationPanel);
            app.Label_2.Text = ' - ';
            app.minAZ = uieditfield(app.AnglelimitationPanel, 'numeric');
            app.minAZ.Limits = [0 360];
            app.minAZ.ValueChangedFcn = createCallbackFcn(app, @minAZValueChanged, true);
            app.SatellitetrackPoldegreePanel = uipanel(app.praSDDtab);
            app.SatellitetrackPoldegreePanel.Title = 'Satellite track - Pol. degree';
            app.TracknoLabel = uilabel(app.SatellitetrackPoldegreePanel);
            app.TracknoLabel.Enable = 'off';
            app.TracknoLabel.Text = 'Track no                 :';
            app.TracknoSpinner = uispinner(app.SatellitetrackPoldegreePanel);
            app.TracknoSpinner.Limits = [1 Inf];
            app.TracknoSpinner.ValueChangedFcn = createCallbackFcn(app, @TracknoSpinnerValueChanged, true);
            app.TracknoSpinner.Enable = 'off';
            app.TracknoSpinner.Value = 1;
            app.PolynomialdegreeSpinnerLabel = uilabel(app.SatellitetrackPoldegreePanel);
            app.PolynomialdegreeSpinnerLabel.Enable = 'off';
            app.PolynomialdegreeSpinnerLabel.Text = 'Polynomial degree :';
            app.PolynomialdegreeSpinner = uispinner(app.SatellitetrackPoldegreePanel);
            app.PolynomialdegreeSpinner.Limits = [1 50];
            app.PolynomialdegreeSpinner.ValueChangedFcn = createCallbackFcn(app, @TracknoSpinnerValueChanged, true);
            app.PolynomialdegreeSpinner.Enable = 'off';
            app.PolynomialdegreeSpinner.Value = 2;
            app.SDAxes = uiaxes(app.praSDDtab);
            xlabel(app.SDAxes, 'Epoch (seconds)')
            ylabel(app.SDAxes, 'SNR (dB)')
            app.SDAxesExpPlot = uibutton(app.praSDDtab, 'push');
            app.SDAxesExpPlot.ButtonPushedFcn = createCallbackFcn(app, @SDAxesExpPlotButtonPushed, true);
            app.SDAxesExpPlot.Text = 'Export plot';
            app.praSVtab = uitab(app.pra_tabs);
            app.praSVtab.Title = 'Sky view';
            app.SkyplotPanel = uipanel(app.praSVtab);
            app.SkyplotPanel.Title = 'Sky plot';
            app.SatellitesLabel_2 = uilabel(app.SkyplotPanel);
            app.SatellitesLabel_2.Text = 'Satellites';
            app.praSLBox_2 = uilistbox(app.SkyplotPanel);
            app.praSLBox_2.Items = {};
            app.praSLBox_2.ValueChangedFcn = createCallbackFcn(app, @praSLBox_2ValueChanged, true);
            app.praSLBox_2.Value = {};
            app.SatellitesPanel = uipanel(app.praSVtab);
            app.SatellitesPanel.Title = 'Satellites';
            app.GPSCheckBox = uicheckbox(app.SatellitesPanel);
            app.GPSCheckBox.ValueChangedFcn = createCallbackFcn(app, @GPSCheckBoxValueChanged, true);
            app.GPSCheckBox.Text = 'GPS';
            app.GPSCheckBox.Value = true;
            app.GLONASSCheckBox = uicheckbox(app.SatellitesPanel);
            app.GLONASSCheckBox.ValueChangedFcn = createCallbackFcn(app, @GPSCheckBoxValueChanged, true);
            app.GLONASSCheckBox.Text = 'GLONASS';
            app.GLONASSCheckBox.Value = true;
            app.GALILEOCheckBox = uicheckbox(app.SatellitesPanel);
            app.GALILEOCheckBox.ValueChangedFcn = createCallbackFcn(app, @GPSCheckBoxValueChanged, true);
            app.GALILEOCheckBox.Text = 'GALILEO';
            app.GALILEOCheckBox.Value = true;
            app.BEIDOUCheckBox = uicheckbox(app.SatellitesPanel);
            app.BEIDOUCheckBox.ValueChangedFcn = createCallbackFcn(app, @GPSCheckBoxValueChanged, true);
            app.BEIDOUCheckBox.Text = 'BEIDOU';
            app.BEIDOUCheckBox.Value = true;
            app.SDAxesExpPlot_2 = uibutton(app.praSVtab, 'push');
            app.SDAxesExpPlot_2.ButtonPushedFcn = createCallbackFcn(app, @SDAxesExpPlot_2ButtonPushed, true);
            app.SDAxesExpPlot_2.Text = 'Export plot';
            app.praSAtab = uitab(app.pra_tabs);
            app.praSAtab.Title = 'FFZ';
            app.ParametersPanel = uipanel(app.praSAtab);
            app.ParametersPanel.Title = 'Parameters';
            app.ReflectorheightEditFieldLabel = uilabel(app.ParametersPanel);
            app.ReflectorheightEditFieldLabel.Text = 'Reflector height :';
            app.ReflectorheightEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ReflectorheightEditField.Limits = [0 Inf];
            app.ReflectorheightEditField.ValueDisplayFormat = '%0.3f';
            app.ReflectorheightEditField.Value = 2;
            app.SNRdataDropDown_2 = uidropdown(app.ParametersPanel);
            app.SNRdataDropDown_2.Items = {};
            app.SNRdataDropDown_2.Value = {};
            app.DataLabel = uilabel(app.ParametersPanel);
            app.DataLabel.Text = 'Data :';
            app.ElevationangleEditFieldLabel = uilabel(app.ParametersPanel);
            app.ElevationangleEditFieldLabel.Text = 'Elevation angle :';
            app.ElevationangleEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ElevationangleEditField.Limits = [0 90];
            app.ElevationangleEditField.ValueDisplayFormat = '%0.f';
            app.ElevationangleEditField.Value = 5;
            app.FFZplotPanel = uipanel(app.praSAtab);
            app.FFZplotPanel.Title = 'FFZ plot';
            app.FFZAxes = uiaxes(app.FFZplotPanel);
            title(app.FFZAxes, '')
            xlabel(app.FFZAxes, 'East (m)')
            ylabel(app.FFZAxes, 'North (m)')
            app.Panel = uipanel(app.FFZplotPanel);
            app.NumberofsatellitetracksEditFieldLabel = uilabel(app.Panel);
            app.NumberofsatellitetracksEditFieldLabel.Text = 'Number of satellite tracks :';
            app.NumberofsatellitetracksEditField = uieditfield(app.Panel, 'numeric');
            app.NumberofsatellitetracksEditField.ValueDisplayFormat = '%.0f';
            app.NumberofsatellitetracksEditField.Editable = 'off';
            app.AreaofanFFZellipsem2EditFieldLabel = uilabel(app.Panel);
            app.AreaofanFFZellipsem2EditFieldLabel.Text = 'Area of an FFZ ellipse (m^2) :';
            app.AreaofanFFZellipsem2EditField = uieditfield(app.Panel, 'numeric');
            app.AreaofanFFZellipsem2EditField.ValueDisplayFormat = '%.2f';
            app.AreaofanFFZellipsem2EditField.Editable = 'off';
            app.TotalFFZaream2EditFieldLabel = uilabel(app.Panel);
            app.TotalFFZaream2EditFieldLabel.Text = 'Total FFZ area (m^2) :';
            app.TotalFFZaream2EditField = uieditfield(app.Panel, 'numeric');
            app.TotalFFZaream2EditField.ValueDisplayFormat = '%.2f';
            app.TotalFFZaream2EditField.Editable = 'off';
            app.DistancefromellipsecenterEditFieldLabel = uilabel(app.Panel);
            app.DistancefromellipsecenterEditFieldLabel.Text = 'Distance from ellipse center : ';
            app.DistancefromellipsecenterEditField = uieditfield(app.Panel, 'numeric');
            app.DistancefromellipsecenterEditField.ValueDisplayFormat = '%.2f';
            app.DistancefromellipsecenterEditField.Editable = 'off';
            app.SDAxesExpPlot_3 = uibutton(app.praSAtab, 'push');
            app.SDAxesExpPlot_3.ButtonPushedFcn = createCallbackFcn(app, @SDAxesExpPlot_3ButtonPushed, true);
            app.SDAxesExpPlot_3.Text = 'Export plot';
            app.SDAxesExpPlot_4 = uibutton(app.praSAtab, 'push');
            app.SDAxesExpPlot_4.ButtonPushedFcn = createCallbackFcn(app, @SDAxesExpPlot_4ButtonPushed, true);
            app.SDAxesExpPlot_4.Text = 'Export KML';
            app.SDAxesExpPlot_5 = uibutton(app.praSAtab, 'push');
            app.SDAxesExpPlot_5.ButtonPushedFcn = createCallbackFcn(app, @SDAxesExpPlot_5ButtonPushed, true);
            app.SDAxesExpPlot_5.Text = 'Plot FFZ';
            app.praSMBrowse = uibutton(app.pra, 'push');
            app.praSMBrowse.ButtonPushedFcn = createCallbackFcn(app, @praSMBrowseButtonPushed, true);
            app.praSMBrowse.Text = 'Browse';
            app.SNRMATfileLabel = uilabel(app.pra);
            app.SNRMATfileLabel.Text = 'SNRMAT file:';
            app.praSMfile = uieditfield(app.pra, 'text');
            app.praSMfile.Editable = 'off';
            app.AnalysisTab = uitab(app.GIRASMenu);
            app.AnalysisTab.Title = 'Analysis';
            app.TabGroup = uitabgroup(app.AnalysisTab);
            app.MakeestimationsTab = uitab(app.TabGroup);
            app.MakeestimationsTab.Title = 'Make estimations';
            app.SettingsPanel_2 = uipanel(app.MakeestimationsTab);
            app.SettingsPanel_2.Title = 'Settings';
            app.SatellitesystemsPanel_2 = uipanel(app.SettingsPanel_2);
            app.SatellitesystemsPanel_2.Title = 'Satellite systems';
            app.GPSCheckBox_2 = uicheckbox(app.SatellitesystemsPanel_2);
            app.GPSCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @GPSCheckBox_2ValueChanged, true);
            app.GPSCheckBox_2.Text = 'GPS';
            app.GPSCheckBox_2.Value = true;
            app.GLONASSCheckBox_2 = uicheckbox(app.SatellitesystemsPanel_2);
            app.GLONASSCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @GPSCheckBox_2ValueChanged, true);
            app.GLONASSCheckBox_2.Text = 'GLONASS';
            app.GLONASSCheckBox_2.Value = true;
            app.GALILEOCheckBox_2 = uicheckbox(app.SatellitesystemsPanel_2);
            app.GALILEOCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @GPSCheckBox_2ValueChanged, true);
            app.GALILEOCheckBox_2.Text = 'GALILEO';
            app.GALILEOCheckBox_2.Value = true;
            app.BEIDOUCheckBox_2 = uicheckbox(app.SatellitesystemsPanel_2);
            app.BEIDOUCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @GPSCheckBox_2ValueChanged, true);
            app.BEIDOUCheckBox_2.Text = 'BEIDOU';
            app.BEIDOUCheckBox_2.Value = true;
            app.SNRdataPanel = uipanel(app.SettingsPanel_2);
            app.SNRdataPanel.Title = 'SNR data';
            app.SNRdataDropDown = uidropdown(app.SNRdataPanel);
            app.SNRdataDropDown.Items = {'All'};
            app.SNRdataDropDown.ValueChangedFcn = createCallbackFcn(app, @GPSCheckBox_2ValueChanged, true);
            app.SNRdataDropDown.Value = 'All';
            app.AnglelimitationPanel_2 = uipanel(app.SettingsPanel_2);
            app.AnglelimitationPanel_2.Title = 'Angle limitation';
            app.ElevationEditFieldLabel = uilabel(app.AnglelimitationPanel_2);
            app.ElevationEditFieldLabel.Text = 'Elevation :';
            app.ElevationEditField = uieditfield(app.AnglelimitationPanel_2, 'text');
            app.ElevationEditField.ValueChangedFcn = createCallbackFcn(app, @ElevationEditFieldValueChanged, true);
            app.ElevationEditField.Value = '[0 90]';
            app.AzimuthEditFieldLabel = uilabel(app.AnglelimitationPanel_2);
            app.AzimuthEditFieldLabel.Text = 'Azimuth :';
            app.AzimuthEditField = uieditfield(app.AnglelimitationPanel_2, 'text');
            app.AzimuthEditField.ValueChangedFcn = createCallbackFcn(app, @AzimuthEditFieldValueChanged, true);
            app.AzimuthEditField.Value = '[0 180; 180 360]';
            app.PoldegPanel = uipanel(app.SettingsPanel_2);
            app.PoldegPanel.Title = 'Pol. deg.';
            app.PolynomialdegreeSpinner_2 = uispinner(app.PoldegPanel);
            app.PolynomialdegreeSpinner_2.Limits = [1 50];
            app.PolynomialdegreeSpinner_2.ValueChangedFcn = createCallbackFcn(app, @GPSCheckBox_2ValueChanged, true);
            app.PolynomialdegreeSpinner_2.Value = 2;
            app.EstimationsettingsPanel = uipanel(app.SettingsPanel_2);
            app.EstimationsettingsPanel.Title = 'Estimation settings';
            app.MaximumreflectorheightmEditFieldLabel = uilabel(app.EstimationsettingsPanel);
            app.MaximumreflectorheightmEditFieldLabel.Text = 'Maximum reflector height (m) : ';
            app.MaximumreflectorheightmEditField = uieditfield(app.EstimationsettingsPanel, 'numeric');
            app.MaximumreflectorheightmEditField.Limits = [0 Inf];
            app.MaximumreflectorheightmEditField.ValueDisplayFormat = '%11.3f';
            app.MaximumreflectorheightmEditField.ValueChangedFcn = createCallbackFcn(app, @GPSCheckBox_2ValueChanged, true);
            app.MaximumreflectorheightmEditField.Value = 5;
            app.DesiredprecisionmEditFieldLabel = uilabel(app.EstimationsettingsPanel);
            app.DesiredprecisionmEditFieldLabel.Text = 'Desired precision (m) : ';
            app.DesiredprecisionmEditField = uieditfield(app.EstimationsettingsPanel, 'numeric');
            app.DesiredprecisionmEditField.Limits = [0.001 1];
            app.DesiredprecisionmEditField.ValueDisplayFormat = '%11.3f';
            app.DesiredprecisionmEditField.ValueChangedFcn = createCallbackFcn(app, @GPSCheckBox_2ValueChanged, true);
            app.DesiredprecisionmEditField.Value = 0.001;
            app.FilenameEditFieldLabel = uilabel(app.EstimationsettingsPanel);
            app.FilenameEditFieldLabel.Text = 'Filename : ';
            app.FilenameEditField = uieditfield(app.EstimationsettingsPanel, 'text');
            app.anaFiles = uipanel(app.MakeestimationsTab);
            app.anaFiles.Title = 'Files';
            app.SNRMATfilesLabel = uilabel(app.anaFiles);
            app.SNRMATfilesLabel.Text = 'SNRMAT files';
            app.anaSFL = uitextarea(app.anaFiles);
            app.anaSFL.Editable = 'off';
            app.anaSFBrowse = uibutton(app.anaFiles, 'push');
            app.anaSFBrowse.ButtonPushedFcn = createCallbackFcn(app, @anaSFBrowseButtonPushed, true);
            app.anaSFBrowse.Text = 'Browse';
            app.anaSFClear = uibutton(app.anaFiles, 'push');
            app.anaSFClear.ButtonPushedFcn = createCallbackFcn(app, @anaSFClearButtonPushed, true);
            app.anaSFClear.Text = 'Clear';
            app.anaNSFmsg = uilabel(app.anaFiles);
            app.anaNSFmsg.Text = '0 SNRMAT files selected.';
            app.rcfRun_2 = uibutton(app.MakeestimationsTab, 'push');
            app.rcfRun_2.ButtonPushedFcn = createCallbackFcn(app, @rcfRun_2ButtonPushed, true);
            app.rcfRun_2.Text = 'RUN';
            app.ProcessingstatusLabel = uilabel(app.MakeestimationsTab);
            app.ProcessingstatusLabel.Text = 'Processing status';
            app.GIRASStatus_2 = uitextarea(app.MakeestimationsTab);
            app.ImproveestimationsTab = uitab(app.TabGroup);
            app.ImproveestimationsTab.Title = 'Improve estimations';
            app.praSMBrowse_2 = uibutton(app.ImproveestimationsTab, 'push');
            app.praSMBrowse_2.ButtonPushedFcn = createCallbackFcn(app, @praSMBrowse_2ButtonPushed, true);
            app.praSMBrowse_2.Text = 'Browse';
            app.EstimationfileLabel = uilabel(app.ImproveestimationsTab);
            app.EstimationfileLabel.Text = 'Estimation file :';
            app.praSMfile_2 = uieditfield(app.ImproveestimationsTab, 'text');
            app.praSMfile_2.Editable = 'off';
            app.SettingsPanel = uipanel(app.ImproveestimationsTab);
            app.SettingsPanel.Title = 'Settings';
            app.QualityanalysisPanel = uipanel(app.SettingsPanel);
            app.QualityanalysisPanel.Title = 'Quality analysis';
            app.MADcb = uicheckbox(app.QualityanalysisPanel);
            app.MADcb.ValueChangedFcn = createCallbackFcn(app, @MADcbValueChanged, true);
            app.MADcb.Text = '            *  Median Absolute Deviation (MAD)  for';
            app.MADcb.Value = true;
            app.BNCcb = uicheckbox(app.QualityanalysisPanel);
            app.BNCcb.ValueChangedFcn = createCallbackFcn(app, @MADcbValueChanged, true);
            app.BNCcb.Text = '            *  Background noise condition (BNC)';
            app.BNCcb.Value = true;
            app.MERcb = uicheckbox(app.QualityanalysisPanel);
            app.MERcb.ValueChangedFcn = createCallbackFcn(app, @MADcbValueChanged, true);
            app.MERcb.Text = 'Minimum elevation angle range : ';
            app.MERcb.Value = true;
            app.MADco = uieditfield(app.QualityanalysisPanel, 'numeric');
            app.MADco.Limits = [1 3];
            app.MADco.ValueChangedFcn = createCallbackFcn(app, @MADcbValueChanged, true);
            app.MADco.Value = 1;
            app.BNCco = uieditfield(app.QualityanalysisPanel, 'numeric');
            app.BNCco.Limits = [1 10];
            app.BNCco.ValueChangedFcn = createCallbackFcn(app, @MADcbValueChanged, true);
            app.BNCco.Value = 4;
            app.MERco = uieditfield(app.QualityanalysisPanel, 'numeric');
            app.MERco.Limits = [0 90];
            app.MERco.ValueChangedFcn = createCallbackFcn(app, @MADcbValueChanged, true);
            app.MERco.Value = 10;
            app.ButtonGroup = uibuttongroup(app.QualityanalysisPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.DailydataButton = uiradiobutton(app.ButtonGroup);
            app.DailydataButton.Text = 'Daily data';
            app.DailydataButton.Value = true;
            app.AlldataButton = uiradiobutton(app.ButtonGroup);
            app.AlldataButton.Text = 'All data';
            app.ApplytoLabel = uilabel(app.ButtonGroup);
            app.ApplytoLabel.Text = 'Apply to';
            app.DropDown = uidropdown(app.QualityanalysisPanel);
            app.DropDown.Items = {'f', 'Amp', 'Pha'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @MADcbValueChanged, true);
            app.DropDown.Value = 'f';
            app.SeeresultsButton = uibutton(app.QualityanalysisPanel, 'push');
            app.SeeresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SeeresultsButtonPushed, true);
            app.SeeresultsButton.Text = 'See results';
            app.MinMaxfrequencyEditFieldLabel = uilabel(app.QualityanalysisPanel);
            app.MinMaxfrequencyEditFieldLabel.Text = 'Min-Max frequency : ';
            app.MinMaxfrequencyEditField = uieditfield(app.QualityanalysisPanel, 'text');
            app.MinMaxfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @MADcbValueChanged, true);
            app.MinMaxfrequencyEditField.Value = '[0 50]';
            app.PlottingPanel = uipanel(app.SettingsPanel);
            app.PlottingPanel.Title = 'Plotting';
            app.PlotresultsButton = uibutton(app.PlottingPanel, 'push');
            app.PlotresultsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotresultsButtonPushed, true);
            app.PlotresultsButton.Text = 'Plot results';
            app.XDataDropDownLabel = uilabel(app.PlottingPanel);
            app.XDataDropDownLabel.Text = 'X Data :';
            app.XDataDropDown = uidropdown(app.PlottingPanel);
            app.XDataDropDown.Items = {'Epoch', 'Mean elevation angle', 'Mean azimuth angle', 'BNC ratio', 'Elevation angle range', 'Number of epochs', 'Number of epochs per sin(\epsilon)'};
            app.XDataDropDown.ValueChangedFcn = createCallbackFcn(app, @XDataDropDownValueChanged, true);
            app.XDataDropDown.Value = 'Epoch';
            app.YDataDropDownLabel = uilabel(app.PlottingPanel);
            app.YDataDropDownLabel.Text = 'Y Data :';
            app.YDataDropDown = uidropdown(app.PlottingPanel);
            app.YDataDropDown.Items = {'Frequency', 'Amplitude', 'Phase', 'Frequency (daily mean)', 'Amplitude (daily mean)', 'Phase (daily mean)'};
            app.YDataDropDown.ValueChangedFcn = createCallbackFcn(app, @XDataDropDownValueChanged, true);
            app.YDataDropDown.Value = 'Frequency';
            app.ResultsPanel = uipanel(app.ImproveestimationsTab);
            app.ResultsPanel.Title = 'Results';
            app.UITable = uitable(app.ResultsPanel);
            app.UITable.ColumnName = {'#'; 'Year'; 'DoY'; 'Sat'; 'Data'; 'ST no'; 'ST type'; 'Frequency'; 'Amplitude'; 'Phase'; 'Min epoch'; 'Max epoch'; 'Num. of epochs'; 'Min elev.'; 'Max elev.'; 'Min azim.'; 'Max azim.'; 'Max P'; 'BN'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true true true true true true true true true false true true true true true true];
            app.SaveresultsPanel = uipanel(app.ImproveestimationsTab);
            app.SaveresultsPanel.Title = 'Save results';
            app.FilenameEditField_2Label = uilabel(app.SaveresultsPanel);
            app.FilenameEditField_2Label.Text = 'Filename : ';
            app.FilenameEditField_2 = uieditfield(app.SaveresultsPanel, 'text');
            app.SaveButton = uibutton(app.ImproveestimationsTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AboutTab = uitab(app.GIRASMenu);
            app.AboutTab.Title = 'About';
            app.AuthorsPanel = uipanel(app.AboutTab);
            app.AuthorsPanel.Title = 'Authors';
            app.TextArea = uitextarea(app.AuthorsPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'This software was developed by Cemali Altuntas and Nursu Tunalioglu from Yildiz Technical University Department of Geomatic Engineering.'};
            app.ContactPanel = uipanel(app.AboutTab);
            app.ContactPanel.Title = 'Contact';
            app.TextArea_2 = uitextarea(app.ContactPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'e-mail: cemali@yildiz.edu.tr'};
            app.CiteasPanel = uipanel(app.AboutTab);
            app.CiteasPanel.Title = 'Cite as';
            app.TextArea_3 = uitextarea(app.CiteasPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'...'};
            app.StatusTextAreaLabel = uilabel(app.GIRASUI);
            app.StatusTextAreaLabel.Text = 'Status';
            app.GIRASStatus = uitextarea(app.GIRASUI);
            app.GIRASLabel = uilabel(app.GIRASUI);
            app.GIRASLabel.Text = 'GIRAS: GNSS-IR ANALYSIS SOFTWARE';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GIRAS

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GIRASUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GIRASUI)
        end
    end
end